classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MitometerUIFigure              matlab.ui.Figure
        SelecthighlightedButton        matlab.ui.control.Button
        SelectfilesLabel               matlab.ui.control.Label
        ImageList                      matlab.ui.control.ListBox
        LoadingLabel                   matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        IJButton_2                     matlab.ui.control.Button
        IJButton                       matlab.ui.control.Button
        OpenFIJIButton                 matlab.ui.control.Button
        MitometerLabel                 matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        Start3DButton                  matlab.ui.control.Button
        Start2DButton                  matlab.ui.control.Button
        SegmentationTab                matlab.ui.container.Tab
        IJButton_3                     matlab.ui.control.Button
        ZPlaneSlider                   matlab.ui.control.Slider
        ZPlaneSliderLabel              matlab.ui.control.Label
        Spinner_2                      matlab.ui.control.Spinner
        SavetifstackButton             matlab.ui.control.Button
        PlaySeg                        matlab.ui.control.Button
        Spinner                        matlab.ui.control.Spinner
        FrameSlider                    matlab.ui.control.Slider
        FrameSliderLabel               matlab.ui.control.Label
        SelectedImageButtonGroup       matlab.ui.container.ButtonGroup
        FinalButton                    matlab.ui.control.ToggleButton
        ObjectsButton                  matlab.ui.control.ToggleButton
        DiffuseButton                  matlab.ui.control.ToggleButton
        OriginalButton                 matlab.ui.control.ToggleButton
        FinalImageLabel                matlab.ui.control.Label
        ObjectsRandomlyLabeled         matlab.ui.control.Label
        DiffuseBackgroundRemovedLabel  matlab.ui.control.Label
        OriginalImageLabel             matlab.ui.control.Label
        segFigFinal                    matlab.ui.control.UIAxes
        segFigObjects                  matlab.ui.control.UIAxes
        segFigMed                      matlab.ui.control.UIAxes
        segFigOG                       matlab.ui.control.UIAxes
        TracksTab                      matlab.ui.container.Tab
        SavetotxtButton_2              matlab.ui.control.Button
        ListBox_2                      matlab.ui.control.ListBox
        CancelButton                   matlab.ui.control.Button
        ConfirmButton                  matlab.ui.control.Button
        RemovetracksButton             matlab.ui.control.Button
        SavetracksLabel                matlab.ui.control.Label
        AllButton                      matlab.ui.control.Button
        SelectedButton                 matlab.ui.control.Button
        ZAspectRatioSpinner            matlab.ui.control.Spinner
        ZAspectRatioSpinnerLabel       matlab.ui.control.Label
        ObjectCheckBox                 matlab.ui.control.CheckBox
        singleTrack                    matlab.ui.control.Spinner
        TracktoviewLabel               matlab.ui.control.Label
        keptTracksThresh               matlab.ui.control.Spinner
        TracklengththresholdLabel      matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        TrackViewLabel                 matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        TrackFig                       matlab.ui.control.UIAxes
        AnalysisTab                    matlab.ui.container.Tab
        OnewayANOVALabel               matlab.ui.control.Label
        pvalueEditField                matlab.ui.control.NumericEditField
        pvalueEditFieldLabel           matlab.ui.control.Label
        GraphtypeButtonGroup           matlab.ui.container.ButtonGroup
        MedianBarButton                matlab.ui.control.RadioButton
        HistogramButton                matlab.ui.control.RadioButton
        ResetgraphButton               matlab.ui.control.Button
        LogscaleCheckBox               matlab.ui.control.CheckBox
        SavetotxtButton                matlab.ui.control.Button
        AddtographButton               matlab.ui.control.Button
        GroupsDropDown                 matlab.ui.control.DropDown
        GroupsDropDownLabel            matlab.ui.control.Label
        CreategroupButton              matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        UIAxes                         matlab.ui.control.UIAxes
        AdvancedSettingsTab            matlab.ui.container.Tab
        TrackingPanel                  matlab.ui.container.Panel
        customWeightCheckbox           matlab.ui.control.CheckBox
        customZAxWeight                matlab.ui.control.NumericEditField
        ZAxis3DonlyEditFieldLabel      matlab.ui.control.Label
        customIntWeight                matlab.ui.control.NumericEditField
        IntensityEditFieldLabel        matlab.ui.control.Label
        customPeriWeight               matlab.ui.control.NumericEditField
        PerimeterSurfaceAreaEditFieldLabel  matlab.ui.control.Label
        customSolWeight                matlab.ui.control.NumericEditField
        SolidityEditFieldLabel         matlab.ui.control.Label
        customMinAxWeight              matlab.ui.control.NumericEditField
        MinorAxisEditFieldLabel        matlab.ui.control.Label
        customMajAxWeight              matlab.ui.control.NumericEditField
        MajorAxisEditFieldLabel        matlab.ui.control.Label
        customAreaWeight               matlab.ui.control.NumericEditField
        AreaVolumeEditFieldLabel       matlab.ui.control.Label
        customDistWeight               matlab.ui.control.NumericEditField
        DistanceEditFieldLabel         matlab.ui.control.Label
        CustomweightsLabel             matlab.ui.control.Label
        fisFusCheckbox                 matlab.ui.control.CheckBox
        customFrameThresh              matlab.ui.control.NumericEditField
        MaxwaittimethresholdsEditFieldLabel  matlab.ui.control.Label
        customDistThresh               matlab.ui.control.NumericEditField
        MaxvelocitythresholdmsEditFieldLabel  matlab.ui.control.Label
        SegmentationPanel              matlab.ui.container.Panel
        threshCheckbox                 matlab.ui.control.CheckBox
        customThrVal                   matlab.ui.control.NumericEditField
        CustomthresholdEditFieldLabel  matlab.ui.control.Label
        gaussCheckbox                  matlab.ui.control.CheckBox
        customSigVal                   matlab.ui.control.NumericEditField
        CustomgaussfiltsigmaEditFieldLabel  matlab.ui.control.Label
        customMaxArea                  matlab.ui.control.NumericEditField
        MaximummitochondrialareaumEditFieldLabel  matlab.ui.control.Label
        customMinArea                  matlab.ui.control.NumericEditField
        MinimummitochondrialareaumEditFieldLabel  matlab.ui.control.Label
        TroubleshootTab                matlab.ui.container.Tab
        Upload3DButton                 matlab.ui.control.Button
        Upload2DButton                 matlab.ui.control.Button
        TrackingPanel_2                matlab.ui.container.Panel
        GoButton_2                     matlab.ui.control.Button
        SetButton_7                    matlab.ui.control.Button
        SetButton_6                    matlab.ui.control.Button
        SetButton_5                    matlab.ui.control.Button
        customWeightCheckbox_2         matlab.ui.control.CheckBox
        customZAxWeight_2              matlab.ui.control.NumericEditField
        ZAxis3DonlyEditFieldLabel_2    matlab.ui.control.Label
        customIntWeight_2              matlab.ui.control.NumericEditField
        IntensityEditFieldLabel_2      matlab.ui.control.Label
        customPeriWeight_2             matlab.ui.control.NumericEditField
        PerimeterSurfaceAreaEditFieldLabel_2  matlab.ui.control.Label
        customSolWeight_2              matlab.ui.control.NumericEditField
        SolidityEditFieldLabel_2       matlab.ui.control.Label
        customMinAxWeight_2            matlab.ui.control.NumericEditField
        MinorAxisEditFieldLabel_2      matlab.ui.control.Label
        customMajAxWeight_2            matlab.ui.control.NumericEditField
        MajorAxisEditFieldLabel_2      matlab.ui.control.Label
        customAreaWeight_2             matlab.ui.control.NumericEditField
        AreaVolumeEditFieldLabel_2     matlab.ui.control.Label
        CustomweightsLabel_2           matlab.ui.control.Label
        fisFusCheckbox_2               matlab.ui.control.CheckBox
        customFrameThresh_2            matlab.ui.control.NumericEditField
        MaxwaittimethresholdsEditFieldLabel_2  matlab.ui.control.Label
        customDistThresh_2             matlab.ui.control.NumericEditField
        MaxvelocitythresholdmsEditFieldLabel_2  matlab.ui.control.Label
        TrackFig_2                     matlab.ui.control.UIAxes
        SegmentationPanel_2            matlab.ui.container.Panel
        IJButton_5                     matlab.ui.control.Button
        ZPlaneSlider_2                 matlab.ui.control.Slider
        ZPlaneSlider_2Label            matlab.ui.control.Label
        FrameSlider_2                  matlab.ui.control.Slider
        FrameSlider_2Label             matlab.ui.control.Label
        Spinner_4                      matlab.ui.control.Spinner
        PlaySeg_2                      matlab.ui.control.Button
        Spinner_3                      matlab.ui.control.Spinner
        GoButton                       matlab.ui.control.Button
        SetButton_4                    matlab.ui.control.Button
        SetButton_3                    matlab.ui.control.Button
        SetButton_2                    matlab.ui.control.Button
        SetButton                      matlab.ui.control.Button
        threshCheckbox_3               matlab.ui.control.CheckBox
        customThrVal_3                 matlab.ui.control.NumericEditField
        CustomthresholdEditFieldLabel_3  matlab.ui.control.Label
        gaussCheckbox_3                matlab.ui.control.CheckBox
        customSigVal_3                 matlab.ui.control.NumericEditField
        CustomgaussfiltsigmaEditFieldLabel_3  matlab.ui.control.Label
        customMaxArea_3                matlab.ui.control.NumericEditField
        MaximummitochondrialareaumEditFieldLabel_3  matlab.ui.control.Label
        customMinArea_3                matlab.ui.control.NumericEditField
        MinimummitochondrialareaumEditFieldLabel_3  matlab.ui.control.Label
        segFigFinal_2                  matlab.ui.control.UIAxes
        DebugButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        ImAll = [];
        fileName = [];
        path % Description
        micronPerPixel
        secondsPerFrame % Description
        totalNumIms % Description
        zPlane
        zDistance % Description
        minArea = 0.3;
        maxArea = 200;
        distThreshMicrons = 1;
        frameThreshSeconds = -1;% Description
        sig
        thr % Description
        fisFusSkip % Description
        weights % Description
        holdAll % Description
        segFigHold % Description
        figNum = 1;% Description
        playOn = 0; % Description
        selectedIm = 1; % Description
        keptTracks % Description
        trackTypeHold % Description
        outlineOn % Description
        listAll = {} % Description
        allSelected % Description
        lengthThresh % Description
        edgeMito
        centerMito % Description
        callGroupingApp % Description
        trackTypeGrouping = "1";
        lengthThreshGrouping = 1; % Description
        groupList = {}; % Description
        groupListName = {}; % Description
        allSelectedParams % Description
        selectedGroup % Description
        groupDyn % Description
        logOn % Description
        graphGroups = {}; % Description
        groupMedVals = {}; % Description
        is3D = false; % Description
        zPlaneSelected=1; % Description
        zRatio % Description
        zAspectRatio = 1;% Description
        trackSave = 1; % Description
        tempFiles;
        tempSegFiles;
        tempTrackFiles;
        showSeg;
        showTrack; % Description
        tempTrack; % Description
        oneFileTrack; % Description
        oneFileDynamics; % Description
        oneFileParameter; % Description
        tsIm % Description
        tsZPlane % Description
        tsMicronPerPixel
        tsSecondsPerFrame
        tsMinArea % Description
        tsImOG % Description
        tsImShow % Description
        tsWeight % Description
        tsZPlaneNum % Description
        tsZDistance % Description
        tsMeanInt % Description
        tsZRatio % Description
        IJon = false; % Description
        IJIm % Description
        imNumT
        tempfiles
        fileNameTemp

    end
    
    methods (Access = private)
        
        function updateSegFigs(app,~,frameNum)
            app.LoadingLabel.Visible = true;
            pause(0.02)
            
            app.segFigHold = app.holdAll;
            segFig = app.segFigHold;
            
            if app.is3D
                labelIm = label2rgb(segFig.extra.L{frameNum}(:,:,app.zPlaneSelected),'hsv','k','shuffle');
                imagesc(app.segFigOG,segFig.ImOG(:,:,frameNum,app.zPlaneSelected))
                imagesc(app.segFigMed,segFig.ImBgRemoved(:,:,frameNum,app.zPlaneSelected))
                imagesc(app.segFigObjects,labelIm)
                imagesc(app.segFigFinal,segFig.Im(:,:,frameNum,app.zPlaneSelected))
            else
                labelIm = label2rgb(segFig.extra.L{frameNum},'hsv','k','shuffle');
                imagesc(app.segFigOG,segFig.ImOG(:,:,frameNum))
                imagesc(app.segFigMed,segFig.ImBgRemoved(:,:,frameNum))
                imagesc(app.segFigObjects,labelIm)
                imagesc(app.segFigFinal,segFig.Im(:,:,frameNum))
            end
            
            app.segFigOG.XLim = [0,size(segFig.ImOG,2)];
            app.segFigOG.YLim = [0,size(segFig.ImOG,1)];
            app.segFigMed.XLim = [0,size(segFig.ImOG,2)];
            app.segFigMed.YLim = [0,size(segFig.ImOG,1)];
            app.segFigObjects.XLim = [0,size(segFig.ImOG,2)];
            app.segFigObjects.YLim = [0,size(segFig.ImOG,1)];
            app.segFigFinal.XLim = [0,size(segFig.ImOG,2)];
            app.segFigFinal.YLim = [0,size(segFig.ImOG,1)];
            
            app.Spinner.Limits = [1,size(segFig.ImOG,3)];
            app.FrameSlider.Limits = [1,size(segFig.ImOG,3)];
            if app.is3D
                app.Spinner_2.Limits = [1,size(segFig.ImOG,4)];
                app.ZPlaneSlider.Enable = true;
                app.Spinner_2.Enable = true;
                app.ZPlaneSlider.Limits = [1,size(segFig.ImOG,4)];
            end
            
            app.LoadingLabel.Visible = false;
            
        end
        
        function updateSegFigFrame(app,frameNum,figNum)
            segFig = app.segFigHold;
            if figNum == 1
                if app.is3D
                    imagesc(app.segFigOG,segFig.ImOG(:,:,frameNum,app.zPlaneSelected))
                else
                    imagesc(app.segFigOG,segFig.ImOG(:,:,frameNum))
                end
            elseif figNum == 2
                if app.is3D
                    imagesc(app.segFigMed,segFig.ImBgRemoved(:,:,frameNum,app.zPlaneSelected))
                else
                    imagesc(app.segFigMed,segFig.ImBgRemoved(:,:,frameNum))
                end
            elseif figNum == 3
                if app.is3D
                    labelIm = label2rgb(segFig.extra.L{frameNum}(:,:,app.zPlaneSelected),'hsv','k','shuffle');
                    imagesc(app.segFigObjects,labelIm)
                else
                    labelIm = label2rgb(segFig.extra.L{frameNum},'hsv','k','shuffle');
                    imagesc(app.segFigObjects,labelIm)
                end
            elseif figNum == 4
                if app.is3D
                    imagesc(app.segFigFinal,segFig.Im(:,:,frameNum,app.zPlaneSelected))
                else
                    imagesc(app.segFigFinal,segFig.Im(:,:,frameNum))
                end
            end
        end
        
        function updateTrackFig(app,trackType,figNum)
            app.LoadingLabel.Visible = true;
            pause(0.02)

            app.keptTracksThresh.Enable = true;
            trackType = 3;
            lenThresh = app.keptTracksThresh.Value;
            noTracksFound = 0;
            if trackType == 1
                [trackList,noTracksFound] = trackLengthThreshold(app.holdAll.track,lenThresh);
            elseif trackType == 3
                if app.is3D
                    [~,~,perfectTrackIdxs] = getTrackingWeights3d(app.holdAll.track);
                else
                    [~,~,perfectTrackIdxs] = getTrackingWeights(app.holdAll.track);
                end
                [trackList,noTracksFound] = trackLengthThreshold(app.holdAll.track,lenThresh,perfectTrackIdxs);
            elseif trackType == 5
                [~,centerIdx] = edgeCenterMito(app.holdAll.track,app.holdAll.mitoCoM,size(app.holdAll.ImOG,3),app.is3D);
                [trackList,noTracksFound] = trackLengthThreshold(app.holdAll.track,lenThresh,centerIdx);
            elseif trackType == 6
                [edgeIdx,~] = edgeCenterMito(app.holdAll.track,app.holdAll.mitoCoM,size(app.holdAll.ImOG,3),app.is3D);
                [trackList,noTracksFound] = trackLengthThreshold(app.holdAll.track,lenThresh,edgeIdx);
            end
          
            app.oneFileTrack = trackList;

            if noTracksFound
                ImOG = app.holdAll.ImOG;
                
                %plot track centroids
                imagesc(app.TrackFig,255-ImOG(:,:,1))
                app.TrackFig.Colormap = gray;
                return
            end
%             if trackType == 1
%                 trackList = app.holdAll(figNum).track;
%             elseif trackType == 2
%                 trackList = app.keptTracks(figNum).track;
%             elseif trackType == 3
%                 trackList = app.holdAll(figNum).allPerfectTracksFinal;
%             elseif trackType == 5
%                 [~,app.centerMito] = edgeCenterMito(app.holdAll(app.selectedIm).track,app.holdAll(app.selectedIm).mitoCoM,size(app.holdAll(app.selectedIm).ImOG,3));
%                 trackList = app.holdAll(figNum).track(app.centerMito);
%             elseif trackType == 6
%                 [app.edgeMito,~] = edgeCenterMito(app.holdAll(app.selectedIm).track,app.holdAll(app.selectedIm).mitoCoM,size(app.holdAll(app.selectedIm).ImOG,3));
%                 trackList = app.holdAll(figNum).track(app.edgeMito);
%             end
            
            if app.outlineOn
                updateTrackFigObject(app,app.selectedIm,trackList)
                app.LoadingLabel.Visible = false;
                
                return
            end
            
            ImOG = app.holdAll.ImOG;
            %plot track centroids
            if app.is3D
                imagesc(app.TrackFig,255-ImOG(:,:,1,app.zPlaneSelected))
%                 axis image
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                t = app.zRatio*app.zAspectRatio;
                v = app.zRatio*app.zAspectRatio;
                app.TrackFig.DataAspectRatio = [app.zRatio*app.zAspectRatio,app.zRatio*app.zAspectRatio,1];

                hold(app.TrackFig,"on")
                for i = 1:length(trackList)
                    maxZ = plotWeightedCentroidApp3D(app,trackList(i));
                    if trackType == 1 || trackType == 2
                        plotFissionEventsApp3D(app,trackList,i)
                        plotFusionEventsApp3D(app,trackList,i)
                    end
                end
                
                app.TrackFig.ZLim = [0,maxZ];

                
                app.oneFileDynamics = getDynamics3d(app.micronPerPixel,app.secondsPerFrame,app.oneFileTrack,app.zRatio);

            else
                imagesc(app.TrackFig,255-ImOG(:,:,1))
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                hold(app.TrackFig,"on")
                
                
                for i = 1:length(trackList)
                    plotWeightedCentroidApp(app,trackList(i))
                    if trackType == 1 || trackType == 2
                        plotFissionEventsApp(app,trackList,i)
                        plotFusionEventsApp(app,trackList,i)
                    end
                end
                app.oneFileDynamics = getDynamics(app.micronPerPixel,app.secondsPerFrame,app.oneFileTrack);

            end
            app.LoadingLabel.Visible = false;
            hold(app.TrackFig,"off")
        end
        
        function updateTrackFigSingle(app,~,trackNum)
            ImOG = app.holdAll.ImOG;
            if app.is3D
                %plot track centroids
                imagesc(app.TrackFig,255-ImOG(:,:,1,app.zPlaneSelected))
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                app.TrackFig.DataAspectRatio = [app.zRatio*app.zAspectRatio,app.zRatio*app.zAspectRatio,1];
                

                hold(app.TrackFig,"on")
                
                maxZ = plotWeightedCentroidApp3D(app,app.holdAll.track(trackNum));
                app.TrackFig.ZLim = [0,maxZ];

            else
                %plot track centroids
                imagesc(app.TrackFig,255-ImOG(:,:,1))
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                hold(app.TrackFig,"on")
                
                plotWeightedCentroidApp(app,app.holdAll.track(trackNum))
            end
            hold(app.TrackFig,"off")
        end
        
        function updateTrackFigObject(app,figNum,trackNum)
            ImOG = app.holdAll.ImOG;
            
            if app.is3D
                imagesc(app.TrackFig,255-ImOG(:,:,1,app.zPlaneSelected))
%                 axis image
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                app.TrackFig.ZLim = [0,size(ImOG,4)*app.zRatio];
                app.TrackFig.DataAspectRatio = [app.zRatio*app.zAspectRatio,app.zRatio*app.zAspectRatio,1];

                hold(app.TrackFig,"on")
                for i = 1:length(trackNum)
                    all1=[];
                    all2=[];
                    all3=[];
                    for frameNum = 1:size(trackNum(i).ConvexHull)
                        all1 = [all1;trackNum(i).ConvexHull{frameNum}(:,1)];
                        all2 = [all2;trackNum(i).ConvexHull{frameNum}(:,2)];
                        all3 = [all3;trackNum(i).ConvexHull{frameNum}(:,3)*app.zRatio];
                    end
                    scatter3(app.TrackFig,all1(:),all2(:),all3(:),20,'MarkerFaceColor','flat','Marker','o','MarkerEdgeColor','k')
                end
                hold(app.TrackFig,"off")
%                 addedAll = zeros(size(ImOG,1),size(ImOG,2),size(ImOG,4));
%                 addedAllTemp = addedAll;
%                 for plotTrack = 1:length(trackNum)
%                     labelImage = plotLables3D(trackNum,app.holdAll(app.selectedIm).extra.L,plotTrack);
%                     for frameNum = 1:size(labelImage,4)
%                         for zPos = 1:size(labelImage,3)
%                             currIm = labelImage(:,:,zPos,frameNum);
%                             addedAllTemp(currIm>0) = frameNum;
%                             addedAll(:,:,zPos) = addedAllTemp(:,:,zPos);
%                         end
%                     end
%                 end
%                 imagesc(app.TrackFig,addedAll)
%                 app.TrackFig.Colormap = jet;
            else
                imagesc(app.TrackFig,255-ImOG(:,:,1))
                app.TrackFig.Colormap = gray;
                app.TrackFig.XLim = [0,size(ImOG,2)];
                app.TrackFig.YLim = [0,size(ImOG,1)];
                hold(app.TrackFig,'on')
                for i = 1:length(trackNum)
                    all1 = trackNum(i).Extrema(:,1:2:end);
                    all2 = trackNum(i).Extrema(:,2:2:end);
                    plot(app.TrackFig,all1(:),all2(:))
                end
                hold(app.TrackFig,'off')

%                 addedAll = zeros(size(ImOG,1),size(ImOG,2));
%                 
%                 for plotTrack = 1:length(trackNum)
%                     labelImage = plotLables(trackNum,app.holdAll(app.selectedIm).extra.L,plotTrack);
%                     for frameNum = 1:size(labelImage,3)
%                         currIm = labelImage(:,:,frameNum);
%                         addedAll(currIm>0) = frameNum;
%                     end
%                 end
%                 imagesc(app.TrackFig,addedAll)
%                 app.TrackFig.Colormap = jet;
            end
        end
        
        function plotWeightedCentroidApp(app,track)
            all1 = track.WeightedCentroid(:,1:2:end);
            all2 = track.WeightedCentroid(:,2:2:end);
            
            plot(app.TrackFig,all1(1),all2(1),'-o',...
                'LineWidth',0.5,...
                'MarkerSize',5,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','b')
            plot(app.TrackFig,all1(end),all2(end),'-o',...
                'LineWidth',0.5,...
                'MarkerSize',5,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','r')
            plot(app.TrackFig,all1(:),all2(:),'k','LineWidth',1);
        end
            
        function maxZ = plotWeightedCentroidApp3D(app,track)
            all1 = track.WeightedCentroid(:,1:3:end);
            all2 = track.WeightedCentroid(:,2:3:end);
            all3First = app.zRatio*(track.WeightedCentroid(:,3:3:end));
            all3 = all3First-min(all3First(:))+(app.zRatio*app.micronPerPixel);
            maxZ = max(all3(:))+(app.zRatio*app.micronPerPixel);
            plot3(app.TrackFig,all1(1),all2(1),all3(1),'-o',...
                'LineWidth',0.5,...
                'MarkerSize',5,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','b')
            plot3(app.TrackFig,all1(end),all2(end),all3(end),'-o',...
                'LineWidth',0.5,...
                'MarkerSize',5,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','r')
            plot3(app.TrackFig,all1(:),all2(:),all3(:),'k','LineWidth',1);
        end
        
        function plotFissionEventsApp(app,track,trackNum)
            if nnz(track(trackNum).fission)>0
                fissionTrack = track(trackNum).fission(track(trackNum).fission>0);
                fissionFrame = track(trackNum).frame(track(trackNum).fission>0)-1;
                OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                while isempty(OGTrackFrame)
                    fissionFrame = fissionFrame-1;
                    OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                end
                
                OGTrackCentroid1 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*2-1);
                OGTrackCentroid2 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*2);
                connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid(1)];
                connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid(2)];
                
                plot(app.TrackFig,connect1,connect2,':x','LineWidth',2,'Color','#7E2F8E')
            end
        end
        
        function plotFissionEventsApp3D(app,track,trackNum)
            if nnz(track(trackNum).fission)>0
                fissionTrack = track(trackNum).fission(track(trackNum).fission>0);
                fissionFrame = track(trackNum).frame(track(trackNum).fission>0)-1;
                OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                while isempty(OGTrackFrame)
                    fissionFrame = fissionFrame-1;
                    OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                end
            
                OGTrackCentroid1 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*3-2);
                OGTrackCentroid2 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*3-1);
                OGTrackCentroid3 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*3);
                connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid(1)];
                connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid(2)];
                connect3First = app.zRatio*([OGTrackCentroid3,track(trackNum).WeightedCentroid(3)]);
                connect3 = connect3First-min(connect3First)+(app.zRatio*app.micronPerPixel);
            
                plot3(app.TrackFig,connect1,connect2,connect3,':x','LineWidth',2,'Color','#7E2F8E')
            end
        end
        
        function plotFusionEventsApp(app,track,trackNum)
            if nnz(track(trackNum).fusion)>0
                fusionTrack = track(trackNum).fusion(track(trackNum).fusion>0);
                fusionFrame = track(trackNum).frame(track(trackNum).fusion>0)+1;
                OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                while isempty(OGTrackFrame) && fusionFrame < 10000
                    fusionFrame = fusionFrame+1;
                    OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                end
                
                if fusionFrame == 10000
                    return
                end
                
                fusionIDX = find(track(trackNum).fusion>0);
                OGTrackCentroid1 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*2-1);
                OGTrackCentroid2 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*2);
                connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid((fusionIDX)*2-1)];
                connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid((fusionIDX)*2)];
                
                plot(app.TrackFig,connect1,connect2,':h','LineWidth',1,'Color','#77AC30')
            end
        end
        
        function plotFusionEventsApp3D(app,track,trackNum)
            if nnz(track(trackNum).fusion)>0
                fusionTrack = track(trackNum).fusion(track(trackNum).fusion>0);
                fusionFrame = track(trackNum).frame(track(trackNum).fusion>0)+1;
                OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                while isempty(OGTrackFrame) && fusionFrame < 10000
                    fusionFrame = fusionFrame+1;
                    OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                end
                
                if fusionFrame == 10000
                    return
                end
            
                fusionIDX = find(track(trackNum).fusion>0);
                OGTrackCentroid1 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*3-2);
                OGTrackCentroid2 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*3-1);
                OGTrackCentroid3 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*3);
                connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid((fusionIDX)*3-2)];
                connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid((fusionIDX)*3-1)];
                connect3First = app.zRatio*([OGTrackCentroid3,track(trackNum).WeightedCentroid((fusionIDX)*3)]);
                connect3 = connect3First-min(connect3First)+(app.zRatio*app.micronPerPixel);
                
                plot3(app.TrackFig,connect1,connect2,connect3,':h','LineWidth',1,'Color','#77AC30')
            end
        end
        
        function createGroup(app,groupName)
            
            app.LoadingLabel.Visible = true;
            pause(0.02)
            
            numFiles = length(app.allSelected);
            %trackType = str2num(app.trackTypeGrouping);
            trackType = 2;
            disp(trackType);
            %lenThresh = app.lengthThreshGrouping;  
            lenThresh = 1;

            if trackType == 1
                load(app.tempTrackFiles{app.allSelected(1)})
                holdTracks = trackLengthThreshold(track,lenThresh);
                for fileNum = 2:numFiles
                    load(app.tempTrackFiles{app.allSelected(fileNum)})
                    preNextTrack = track;
                    nextTrack = trackLengthThreshold(preNextTrack,lenThresh);
                    holdTracks = [holdTracks,nextTrack];
                end
            elseif trackType == 2
                opi = app.allSelected;
                op = char(app.tempTrackFiles{end});
                % tp97b72
               % ob = app.allSelected(1);
                clear track
                load(op)
                [~,~,perfectTrackIdxs] = getTrackingWeights(track);
                holdTracks = trackLengthThreshold(track,lenThresh,perfectTrackIdxs);
                for fileNum = 2:numFiles
                    load(app.tempTrackFiles{app.allSelected(fileNum)})
                    [~,~,perfectTrackIdxs] = getTrackingWeights3d(track);
                    preNextTrack = track;
                    nextTrack = trackLengthThreshold(preNextTrack,lenThresh,perfectTrackIdxs);
                    holdTracks = [holdTracks,nextTrack];
                end
            elseif trackType == 3
                load(app.tempTrackFiles{app.allSelected(1)})
                [~,centerIdx] = edgeCenterMito(track,mitoCoM,max([track.frame]),app.is3D);
                holdTracks = trackLengthThreshold(track,lenThresh,centerIdx);
                for fileNum = 2:numFiles
                    load(app.tempTrackFiles{app.allSelected(fileNum)})
                    [~,centerIdx] = edgeCenterMito(track,mitoCoM,max([track.frame]),app.is3D);
                    preNextTrack = track;
                    nextTrack = trackLengthThreshold(preNextTrack,lenThresh,centerIdx);
                    holdTracks = [holdTracks,nextTrack];
                end
            elseif trackType == 4
                load(app.tempTrackFiles{app.allSelected(1)})
                [edgeIdx,~] = edgeCenterMito(track,mitoCoM,max([track.frame]),app.is3D);
                holdTracks = trackLengthThreshold(track,lenThresh,edgeIdx);
                for fileNum = 2:numFiles
                    load(app.tempTrackFiles{app.allSelected(fileNum)})
                    [edgeIdx,~] = edgeCenterMito(track,mitoCoM,max([track.frame]),app.is3D);
                    preNextTrack = track;
                    nextTrack = trackLengthThreshold(preNextTrack,lenThresh,edgeIdx);
                    holdTracks = [holdTracks,nextTrack];
                end
            end

            if app.is3D
                holdDyn = getDynamics3d(app.micronPerPixel,app.secondsPerFrame,holdTracks,app.zDistance);
            else
                holdDyn = getDynamics(app.micronPerPixel,app.secondsPerFrame,holdTracks);
            end
            holdBoth = holdTracks;

            for i = 1:length(holdTracks)
                holdBoth(i).speed = holdDyn.speed{i};
                holdBoth(i).distance = holdDyn.distance{i};
                holdBoth(i).displacement = holdDyn.displacement{i};
                holdBoth(i).velocity = holdDyn.velocity{i};
            end
            clear app.groupDyn app.groupList app.groupListName
            app.groupDyn = [app.groupDyn,{holdDyn}];
            app.groupList = [app.groupList,{holdBoth}];
            app.groupListName = [app.groupListName,groupName];
            app.GroupsDropDown.Enable = true;
            
            updateGroupList(app)
            
%             app.ListBox.Value = app.groupListName(1);
            app.AddtographButton.Enable = true;
            app.SavetotxtButton.Enable = true;
            
            app.LoadingLabel.Visible = false;
        end
        
        function updateGroupList(app)
            %app.GroupsDropDownValueChanged(app)
            clear app.GroupsDropDown.Items app.GroupsDropDown.ItemsData
            app.GroupsDropDown.Items = app.groupListName;
            app.GroupsDropDown.ItemsData = app.groupList;
        end
        
        function updateGraph(app)
            
            cla(app.UIAxes,"reset")
            
            toGraph = app.graphGroups;
            
            if app.logOn
                for i = 1:length(app.graphGroups)
                    toGraph{i} = log10(app.graphGroups{i});
                end
            end
            
            if app.HistogramButton.Value
                for i = 1:length(toGraph)
                    histogram(app.UIAxes,toGraph{i},40,'Normalization','probability')
                    hold(app.UIAxes,'on')
                end
                app.UIAxes.YLabel.String = "Fraction of Mitochondria";
                for i = 1:length(app.ListBox.Items)
                    if strcmp(app.ListBox.ItemsData{i},app.allSelectedParams{1})
                        app.UIAxes.XLabel.String = app.ListBox.Items{i};
                    end
                end
            elseif app.MedianBarButton.Value
                for i = 1:length(toGraph)
                    barToPlot(i) = nanmedian(toGraph{i});
                    hold(app.UIAxes,'on')
                end
                bar(app.UIAxes,barToPlot)
                app.UIAxes.XLabel.String = "Group number";
                for i = 1:length(app.ListBox.Items)
                    if strcmp(app.ListBox.ItemsData{i},app.allSelectedParams{1})
                        app.UIAxes.YLabel.String = app.ListBox.Items{i};
                    end
                end
            end
            hold(app.UIAxes,'off')
            
            dataList = [];
            labelList = [];
            for i = 1:length(toGraph)
                dataList = [dataList;toGraph{i}(:)];
                labelList = [labelList;ones(length(toGraph{i}),1)*i];
            end
            
            p = anova1(dataList,labelList,"off");
            
            if ~isnan(p)
                app.pvalueEditField.Value = p; 
            else
                app.pvalueEditField.Value = 1;
            end
            
        end
        
        function updateThreshSegFig(app,image,frameNum,ts3D)
            app.LoadingLabel.Visible = true;
            pause(0.02)
            
            if ts3D
                imagesc(app.segFigFinal_2,image{1}(:,:,frameNum,1))
            else
                imagesc(app.segFigFinal_2,image{1}(:,:,frameNum))
            end
            
            app.segFigFinal_2.XLim = [0,size(image{1},2)];
            app.segFigFinal_2.YLim = [0,size(image{1},1)];
            
            app.Spinner_3.Limits = [1,size(image{1},3)];
            app.FrameSlider_2.Limits = [1,size(image{1},3)];
            if ts3D
                app.Spinner_4.Limits = [1,size(image{1},4)];
                app.ZPlaneSlider_2.Enable = true;
                app.Spinner_4.Enable = true;
                app.ZPlaneSlider_2.Limits = [1,size(image{1},4)];
            end
            
            app.LoadingLabel.Visible = false;
            
        end
        
        function updateThreshSegFigFrame(app,frameNum,ts3D)
            if ts3D
                imagesc(app.segFigFinal_2,app.tsImShow{1}(:,:,frameNum,app.tsZPlane))
            else
                imagesc(app.segFigFinal_2,app.tsImShow{1}(:,:,frameNum))
            end
        end
        
        function tsPlotWeightedCentroidApp(app,track,ts3D)
            if ~ts3D
                all1 = track.WeightedCentroid(:,1:2:end);
                all2 = track.WeightedCentroid(:,2:2:end);
                
                plot(app.TrackFig_2,all1(1),all2(1),'-o',...
                    'LineWidth',0.5,...
                    'MarkerSize',5,...
                    'MarkerEdgeColor','k',...
                    'MarkerFaceColor','b')
                plot(app.TrackFig_2,all1(end),all2(end),'-o',...
                    'LineWidth',0.5,...
                    'MarkerSize',5,...
                    'MarkerEdgeColor','k',...
                    'MarkerFaceColor','r')
                plot(app.TrackFig_2,all1(:),all2(:),'k','LineWidth',1);
            else    
                all1 = track.WeightedCentroid(:,1:3:end);
                all2 = track.WeightedCentroid(:,2:3:end);
                all3 = track.WeightedCentroid(:,3:3:end)*app.tsZRatio;
                plot3(app.TrackFig_2,all1(1),all2(1),all3(1),'-o',...
                    'LineWidth',0.5,...
                    'MarkerSize',5,...
                    'MarkerEdgeColor','k',...
                    'MarkerFaceColor','b')
                plot3(app.TrackFig_2,all1(end),all2(end),all3(end),'-o',...
                    'LineWidth',0.5,...
                    'MarkerSize',5,...
                    'MarkerEdgeColor','k',...
                    'MarkerFaceColor','r')
                plot3(app.TrackFig_2,all1(:),all2(:),all3(:),'k','LineWidth',1);
            end
        end
        
        function tsPlotFissionEventsApp(app,track,trackNum,ts3D)
            if ~ts3D
                if nnz(track(trackNum).fission)>0
                    fissionTrack = track(trackNum).fission(track(trackNum).fission>0);
                    fissionFrame = track(trackNum).frame(track(trackNum).fission>0)-1;
                    OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                    while isempty(OGTrackFrame)
                        fissionFrame = fissionFrame-1;
                        OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                    end
                    
                    OGTrackCentroid1 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*2-1);
                    OGTrackCentroid2 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*2);
                    connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid(1)];
                    connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid(2)];
                    
                    plot(app.TrackFig_2,connect1,connect2,':x','LineWidth',2,'Color','#7E2F8E')
                end
            else
                if nnz(track(trackNum).fission)>0
                    fissionTrack = track(trackNum).fission(track(trackNum).fission>0);
                    fissionFrame = track(trackNum).frame(track(trackNum).fission>0)-1;
                    OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                    while isempty(OGTrackFrame)
                        fissionFrame = fissionFrame-1;
                        OGTrackFrame = track(fissionTrack).frame(track(fissionTrack).frame==fissionFrame);
                    end
                
                    OGTrackCentroid1 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*3-2);
                    OGTrackCentroid2 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*3-1);
                    OGTrackCentroid3 = track(fissionTrack).WeightedCentroid(find(track(fissionTrack).frame == OGTrackFrame)*3);
                    connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid(1)];
                    connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid(2)];
                    connect3 = [OGTrackCentroid3,track(trackNum).WeightedCentroid(3)]*app.tsZRatio;
                
                    plot3(app.TrackFig_2,connect1,connect2,connect3,':x','LineWidth',2,'Color','#7E2F8E')
                end
            end
        end
        
        function tsPlotFusionEventsApp(app,track,trackNum,ts3D)
            if ~ts3D
                if nnz(track(trackNum).fusion)>0
                    fusionTrack = track(trackNum).fusion(track(trackNum).fusion>0);
                    fusionFrame = track(trackNum).frame(track(trackNum).fusion>0)+1;
                    OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                    while isempty(OGTrackFrame) && fusionFrame < 10000
                        fusionFrame = fusionFrame+1;
                        OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                    end
                    
                    if fusionFrame == 10000
                        return
                    end
                    
                    fusionIDX = find(track(trackNum).fusion>0);
                    OGTrackCentroid1 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*2-1);
                    OGTrackCentroid2 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*2);
                    connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid((fusionIDX)*2-1)];
                    connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid((fusionIDX)*2)];
                    
                    plot(app.TrackFig_2,connect1,connect2,':h','LineWidth',1,'Color','#77AC30')
                end
            else
                if nnz(track(trackNum).fusion)>0
                    fusionTrack = track(trackNum).fusion(track(trackNum).fusion>0);
                    fusionFrame = track(trackNum).frame(track(trackNum).fusion>0)+1;
                    OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                    while isempty(OGTrackFrame) && fusionFrame < 10000
                        fusionFrame = fusionFrame+1;
                        OGTrackFrame = track(fusionTrack).frame(track(fusionTrack).frame==fusionFrame);
                    end
                    
                    if fusionFrame == 10000
                        return
                    end
                
                    fusionIDX = find(track(trackNum).fusion>0);
                    OGTrackCentroid1 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*3-2);
                    OGTrackCentroid2 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*3-1);
                    OGTrackCentroid3 = track(fusionTrack).WeightedCentroid(find(track(fusionTrack).frame == OGTrackFrame)*3);
                    connect1 = [OGTrackCentroid1,track(trackNum).WeightedCentroid((fusionIDX)*3-2)];
                    connect2 = [OGTrackCentroid2,track(trackNum).WeightedCentroid((fusionIDX)*3-1)];
                    connect3 = [OGTrackCentroid3,track(trackNum).WeightedCentroid((fusionIDX)*3)]*app.tsZRatio;
                
                    plot3(app.TrackFig_2,connect1,connect2,connect3,':h','LineWidth',1,'Color','#77AC30')
                end
            end
        end
        
        function openInFIJI(app,Im)
            imFIJI = flipIJIm(Im);
            assignin('base',"imFIJI",imFIJI)
            evalin('base',"IJM.show('imFIJI')")
        end
    end
    
    methods (Access = public)
        
        function groupingParams(app,newLengthThresh,newTrackType,groupName)
            
            app.lengthThreshGrouping = 1;
            app.trackTypeGrouping = 'Perfect only';
            createGroup(app,'tmp');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoadingLabel.Visible = true;
            app.Start2DButton.Enable = false;
            app.Start3DButton.Enable = false;
            app.ResetButton.Enable = false;
            
            %f = waitbar(0,'Loading Mitometer');
            %pause(.05)
            
            %waitbar(.33,f,'Loading Mitometer');
            %pause(0.05)
            
            %waitbar(.67,f,'Loading Mitometer');
            %pause(0.05)
            
            %waitbar(1,f,'Loading Mitometer');
            %pause(0.05)
            
            %close(f)
            
            thisVersion = num2str(textread('version.txt'));
            updateAvailable = checkUpdates();
            
            if updateAvailable
                versionTxt = strcat('v', thisVersion,', update available on Github.');
            else
                versionTxt = strcat('v', thisVersion,', up to date');
            end
            
            app.Label.Text = versionTxt;
            
            app.LoadingLabel.Visible = false;
            app.Start2DButton.Enable = true;
            app.Start3DButton.Enable = true;
            app.ResetButton.Enable = true;
        end

        % Button pushed function: Start2DButton
        function Start2DButtonPushed(app, event)
            
            % Change button name to "Processing"
            app.Start2DButton.Text = 'Processing...';
            app.Start2DButton.FontSize = 30;
            % Put text on top of icon
            app.Start2DButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            %wbar = permute(repmat(app.Start2DButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            %wbar([1,end],:,:) = 0;
            %wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            %app.Start2DButton.Icon = wbar;
            % Loop through something and update waitbar
            
            clear holdAllTemp holdOG 
            
            % Ask for image parameters
            [app.micronPerPixel, app.secondsPerFrame] = start2D();
            
            % Disable 3D
            app.Start3DButton.Enable = false;
            
            if ~app.IJon
                [ImAllTemp,~,fileNameTempn,app.path] = openTifs(); %open all selected images
            else
                ImAllTemp = {app.IJIm};
                prompt = {'Name your file:'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'IJfile'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                fileNameTempn = answer;
                app.path = pwd;
            end
            app.fileName = [app.fileName, fileNameTempn]; %list of filenames
            
            % Store all images in own temp file for memory's sake
            for i = 1:length(ImAllTemp)
                tempFileName = tempname;
                holdOG.ImAll = ImAllTemp{i};
                
                save(tempFileName,"holdOG");

                app.tempFiles = [app.tempFiles,{tempFileName}]; %list of tempnames
            end
            
            app.totalNumIms = length(app.tempFiles); %total number of images
            numIms = length(ImAllTemp); %number of images just selected
            
            % Segment and track one by one all selected images.
            for imNum = 1:numIms
                clear temp
                clear ImBgRemoved
                clear ImMinMedProj
                clear ImMedFilteredProj
                clear meanInt
                % Display progress on Start 2D button
                app.Start2DButton.Text = strcat("Image ",num2str(imNum)," of ",num2str(numIms));
                %currentProg = min(round((size(wbar,2)-2)*((imNum-1)/numIms)),size(wbar,2)-2);
                %app.Start2DButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25;
                %app.Start2DButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.50;
                %app.Start2DButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.75;
                pause(0.002)
                
                % Load in current image
                load(app.tempFiles{end-numIms+imNum})
                ImOG = holdOG.ImAll;

                % Search time threshold is full length if -1 (default)
                if app.frameThreshSeconds == -1
                    useframeThreshSeconds = size(ImOG,3);
                else
                    useframeThreshSeconds = app.frameThreshSeconds;
                end
                
                holdAllTemp.micronPerPixel = app.micronPerPixel;
                holdAllTemp.secondsPerFrame = app.secondsPerFrame;
                holdAllTemp.fileName = fileNameTempn{imNum};
                app.fileNameTemp = fileNameTempn{imNum};
                holdAllTemp.path = app.path;
                
                % Set min area to at least 4 pixels in area, and at least
                % the intended minimum area
                minAreaReal = max(4*app.micronPerPixel,app.minArea);
                
                %%%%
                %% Segmentation

                % Remove diffuse bg
                [ImBgRemoved,ImMinMed,ImMedFiltered] = diffuseBgRemove(ImOG,(minAreaReal)/app.micronPerPixel,(1)/app.micronPerPixel);
                
                holdAllTemp.ImBgRemoved = ImBgRemoved;
                holdAllTemp.ImMinMed = ImMinMed;
                holdAllTemp.ImMedFiltered = ImMedFiltered;
                
                % Find optimal gaussian filter sigma and threshold value
                if ~app.gaussCheckbox.Value || ~app.threshCheckbox.Value
                    [app.sig,app.thr,costs] = optimizeSigmaThresh(ImBgRemoved);
                    holdAllTemp.costs = costs;
                else
                    holdAllTemp.costs = [];
                end
                if app.gaussCheckbox.Value
                    app.sig = app.customSigVal.Value;
                end
                if app.threshCheckbox.Value
                    app.thr = app.customThrVal.Value;
                end
                holdAllTemp.sig = app.sig;
                holdAllTemp.thr = app.thr;
                
                % Gaussian filter for low-pass filter and smooth discontinuities.
                ImGaussFiltered = gaussFilter(ImBgRemoved,app.sig);
                holdAllTemp.ImGaussFiltered = ImGaussFiltered;
                
                % Threshold image to remove adjacent mitochondria connections and bg.
                ImMask = thresholdImage(ImGaussFiltered,app.thr);
                holdAllTemp.ImMask = ImMask;
                
                % Apply mask
                ImMaskThresholded = areaThreshold(ImMask,minAreaReal/(app.micronPerPixel^2),app.maxArea/(app.micronPerPixel^2));
                Im = ImMaskThresholded.*ImOG;
                holdAllTemp.ImMaskThresholded = ImMaskThresholded;
                holdAllTemp.Im = Im;
                
                %%%%
                %% Tracking
                
                % Preliminary tracking to set weighting
                if ~app.customWeightCheckbox.Value
                    weightsPrelim = ones(1,6)*1/6;
                    trackPrelim = trackMitochondria(Im,ImOG,weightsPrelim,app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds,0,1);
                    % Get the weights
                    [app.weights,~] = getTrackingWeights(trackPrelim);
                    if sum(isnan(app.weights))
                        app.weights = weightsPrelim;
                    end
                end
                holdAllTemp.weightsFinal = app.weights;
                
                % Real tracking with new weights
                if ~app.fisFusCheckbox.Value %check fission and fusion too
                    [track,mitoCoM,extra] = trackMitochondria(Im,ImOG,app.weights(1:6),app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds);
                else
                    [track,mitoCoM,extra] = trackMitochondria(Im,ImOG,app.weights(1:6),app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds,0,1);
                end
                holdAllTemp.track = track;
                holdAllTemp.mitoCoM = mitoCoM;
                holdAllTemp.extra = extra;
                
                % Get the weights and perfect tracks
                [~,allPerfectTracksFinal] = getTrackingWeights(track);
                holdAllTemp.allPerfectTracksFinal = allPerfectTracksFinal;
                
                % Get dynamics
                dynamics = getDynamics(app.micronPerPixel,app.secondsPerFrame,track);
                holdAllTemp.dynamics = dynamics;
                
                % Save in a temporary file
                tempFileName = tempname;
                save(tempFileName,'holdAllTemp');
                app.tempSegFiles = [app.tempSegFiles,{tempFileName}]; %list of tempnames for segmentation parameters
                
                % Save just track in a temporary file
                tempFileName = tempname;
                save(tempFileName,'track','mitoCoM');

                app.tempTrackFiles = [app.tempTrackFiles,{tempFileName}]; %list of tempnames for segmentation parameters
                SelecthighlightedButtonPushed(app,event)
                CreategroupButtonPushed(app, event);
                app.imNumT = imNum;
                SavetotxtButtonPushed(app, event);
                clear app.tempSegFiles app.tempTrackFiles holdAllTemp dynamics ImStack ImFinal 
            end
            
            % Add file names to selectable table
            app.listAll = app.fileName;
            app.ImageList.Items = app.listAll;
            app.ImageList.ItemsData = (1:length(app.listAll));
            
            % Remove waitbar
            app.Start2DButton.Icon = '';
            % Change button name back
            app.Start2DButton.Text = 'Add 2D';
            app.Start2DButton.FontSize = 60;
            
            app.ImageList.Value = 1;
            ImageListValueChanged(app,event)
            
%             SelecthighlightedButtonPushed(app,event)
%             
%             app.selectedIm = 1;
%             updateSegFigs(app,app.selectedIm,1)
%             updateTrackFig(app,1,app.selectedIm)
        end

        % Button pushed function: Start3DButton
        function Start3DButtonPushed(app, event)
            addpath(strcat(pwd,'/3D'));
            
            % Change button name to "Processing"
            app.Start3DButton.Text = 'Processing...';
            app.Start3DButton.FontSize = 30;
            % Put text on top of icon
            app.Start3DButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.Start3DButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.Start3DButton.Icon = wbar;
            % Loop through something and update waitbar
            
            clear holdAllTemp holdOG 
            
            % Ask for image parameters
            [app.micronPerPixel, app.secondsPerFrame, ~,app.zDistance] = start3D(1);
            %if ~app.IJon
            %    [app.micronPerPixel, app.secondsPerFrame, app.zPlane, app.zDistance] = start3D(0);
            %else
                
            %end
            
            % Disable 2D
            app.Start2DButton.Enable = false;
            app.is3D = true;
            
            if ~app.IJon
                [ImAllTemp,~,fileNameTempn,app.path] = openTifs(app.zPlane); %open all selected images
            else
                ImAllTemp = {app.IJIm};
                prompt = {'Name your file:'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'IJfile'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                fileNameTempn = answer;
                app.path = pwd;
            end
            app.fileName = [app.fileName, fileNameTempn]; %list of filenames
            
            % Store all images in own temp file for memory's sake
            for i = 1:length(ImAllTemp)
                tempFileName = tempname;
                holdOG.ImAll = ImAllTemp{i};
                
                save(tempFileName,"holdOG");

                app.tempFiles = [app.tempFiles,{tempFileName}]; %list of tempnames
            end
            
            app.totalNumIms = length(app.tempFiles); %total number of images
            numIms = length(ImAllTemp); %number of images just selected
            
            minAreaReal = max(4*app.micronPerPixel,app.minArea);

            % Segment and track one by one all selected images.
            for imNum = 1:numIms
                
                % Display progress on Start 3D button
                app.Start3DButton.Text = strcat("Image ",num2str(imNum)," of ",num2str(numIms));
                currentProg = min(round((size(wbar,2)-2)*(imNum-1/numIms)),size(wbar,2)-2);
                app.Start3DButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.Start3DButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.Start3DButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.002)
                
                % Load in current image
                load(app.tempFiles{end-numIms+imNum})
                ImStack = uint8(holdOG.ImAll);

                numStacks = size(ImStack,4);
                % Search time threshold is full length if -1 (default)
                if app.frameThreshSeconds == -1
                    useframeThreshSeconds = size(ImStack,3);
                else
                    useframeThreshSeconds = app.frameThreshSeconds;
                end
                
                holdAllTemp.micronPerPixel = app.micronPerPixel;
                holdAllTemp.secondsPerFrame = app.secondsPerFrame;
                holdAllTemp.fileName = fileNameTempn{imNum};
                holdAllTemp.path = app.path;
                
                %%%%
                %% Segmentation

                % Remove diffuse bg
                clear temp
                clear ImBgRemoved
                clear ImMinMedProj
                clear ImMedFilteredProj
                clear meanInt
                %ImBgRemoved = zeros(size(ImStack),'like',ImStack);
                %ImMinMedProj = zeros(size(ImStack),'like',ImStack);
                %ImMedFilteredProj = zeros(size(ImStack),'like',ImStack);
                meanInt = zeros(1,numStacks);
                for stackNum = 1:numStacks
                    sprintf("Plane %d of %d.",stackNum,numStacks)
                    temp(:,:,:) = ImStack(:,:,:,stackNum);
                    [ImBgRemoved(:,:,:,stackNum),ImMinMedProj(:,:,:,stackNum),ImMedFilteredProj(:,:,:,stackNum)] = diffuseBgRemove(temp,minAreaReal/app.micronPerPixel,1/app.micronPerPixel);
                    meanInt(stackNum) = mean(mean(mean(ImBgRemoved(:,:,1:min(10,size(ImBgRemoved,3)),stackNum))));
                end
                                
                holdAllTemp.ImBgRemoved = ImBgRemoved;
                holdAllTemp.ImMinMed = ImMinMedProj;
                holdAllTemp.ImMedFiltered = ImMedFilteredProj;
                
                % Find optimal gaussian filter sigma and threshold value
                clear temp
                if ~app.gaussCheckbox.Value || ~app.threshCheckbox.Value
                    [app.sig,app.thr,costs] = optimizeSigmaThresh(ImBgRemoved(:,:,:,find(meanInt==max(meanInt))));
                    holdAllTemp.costs = costs;
                else
                    holdAllTemp.costs = [];
                end
                if app.gaussCheckbox.Value
                    app.sig = app.customSigVal.Value;
                end
                if app.threshCheckbox.Value
                    app.thr = app.customThrVal.Value;
                end
                holdAllTemp.sig = app.sig;
                holdAllTemp.thr = app.thr;
                
                ImGaussFiltered = zeros(size(ImStack),'like',ImStack);
                ImMask = zeros(size(ImStack),'like',ImStack);
                ImMaskThresholded = zeros(size(ImStack),'like',ImStack);
                Im = zeros(size(ImStack),'like',ImStack);
                
                for stackNum = 1:numStacks
                    temp(:,:,:) = ImBgRemoved(:,:,:,stackNum);
                    
                    % Gaussian filter for low-pass filter and smooth discontinuities.
                    ImGaussFiltered(:,:,:,stackNum) = gaussFilter(temp,app.sig);
                    
                    % Threshold image to remove adjacent mitochondria connections and bg.
                    ImMask(:,:,:,stackNum) = thresholdImage(ImGaussFiltered(:,:,:,stackNum),app.thr);
                    
                    % Area threshold and apply mask
                    ImMaskThresholded(:,:,:,stackNum) = uint8(areaThreshold(ImMask(:,:,:,stackNum),minAreaReal/(app.micronPerPixel^2),app.maxArea/app.micronPerPixel^2));
                    Im(:,:,:,stackNum) = ImStack(:,:,:,stackNum).*ImMaskThresholded(:,:,:,stackNum);
                end
                holdAllTemp.ImGaussFiltered = ImGaussFiltered;
                holdAllTemp.ImMask = ImMask;
                
                clear temp
                numFrames = size(Im,3);
                threshIm = zeros(size(ImStack),'like',ImStack);

                for frameNum = 1:numFrames
                    temp(:,:,:) = Im(:,:,frameNum,:);
                    CCPreThresh = bwconncomp(temp);
                    statsPreThresh = regionprops3(CCPreThresh);
                    
                    %Remove components existing in only one plane
                    keptComponents = find(mod(statsPreThresh.Centroid(:,3),1)~=0);
                    
                    %Make a new image with only kept components
                    threshIm(:,:,frameNum,:) = ismember(labelmatrix(CCPreThresh),keptComponents);
                end                
                ImFinal = Im.*uint8(threshIm);
                
                holdAllTemp.ImMaskThresholded = threshIm;
                holdAllTemp.Im = ImFinal;
                
                %%%%
                %% Tracking
                                
                % Preliminary tracking to set weighting
                if ~app.customWeightCheckbox.Value
                    weightsPrelim = ones(1,7)*1/7;
                    trackPrelim = trackMitochondria3d(ImFinal,ImStack,weightsPrelim,app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds,app.zDistance,0,1);
                    % Get the weights
                    [app.weights,~] = getTrackingWeights3d(trackPrelim);
                    if sum(isnan(app.weights))
                        app.weights = weightsPrelim;
                    end
                end
                
                holdAllTemp.weightsFinal = app.weights;
                
                % Real tracking with new weights
                if ~app.fisFusCheckbox.Value %check fission and fusion too
                    [track,mitoCoM,extra] = trackMitochondria3d(ImFinal,ImStack,app.weights,app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds,app.zDistance);
                else
                    [track,mitoCoM,extra] = trackMitochondria3d(ImFinal,ImStack,app.weights,app.micronPerPixel,app.secondsPerFrame,app.distThreshMicrons,useframeThreshSeconds,app.zDistance,0,1);
                end
                holdAllTemp.track = track;
                holdAllTemp.mitoCoM = mitoCoM;
                holdAllTemp.extra = extra;
                
                % Get the weights and perfect tracks
                [~,allPerfectTracksFinal] = getTrackingWeights3d(track);
                holdAllTemp.allPerfectTracksFinal = allPerfectTracksFinal;
                
                % Get dynamics
                dynamics = getDynamics3d(app.micronPerPixel,app.secondsPerFrame,track,app.zDistance);
                holdAllTemp.dynamics = dynamics;
                
                % Save in a temporary file
                tempFileName = tempname;
                save(tempFileName,'holdAllTemp');
                app.tempSegFiles = [app.tempSegFiles,{tempFileName}]; %list of tempnames for segmentation parameters
                
                % Save just track in a temporary file
                tempFileName = tempname;
                save(tempFileName,'track','mitoCoM');
                app.tempTrackFiles = [app.tempTrackFiles,{tempFileName}]; %list of tempnames for segmentation parameters


                % remove waitbar
                app.Start3DButton.Icon = '';
                % Change button name
                app.Start3DButton.Text = 'Add 3D';
                app.Start3DButton.FontSize = 60;
                
                app.zRatio = app.zDistance/app.micronPerPixel;
                app.ZAspectRatioSpinner.Enable = true;
                
                
                %ImageListValueChanged(app,event)
                SelecthighlightedButtonPushed(app,event)
                CreategroupButtonPushed(app, event);
                app.imNumT = imNum;
                SavetotxtButtonPushed(app, event);
                clear app.tempSegFiles app.tempTrackFiles holdAllTemp dynamics ImStack ImFinal 
                        
            end
        app.listAll = app.fileName;
        %app.ImageList.Items = app.listAll;
        %app.ImageList.Value = 1;
        %app.ImageList.ItemsData = (1:length(app.listAll));
            % Add file names to selectable table

%             SelecthighlightedButtonPushed(app,event)
%             
%             app.selectedIm = 1;
%             updateSegFigs(app,app.selectedIm,1)
%             updateTrackFig(app,1,app.selectedIm)
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
app.holdAll.track
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            confirmClear = sprintf('Do you really want to clear the workspace?');
            buttonConfirmClear = questdlg(confirmClear, 'Clear all', 'Yes', 'No', 'No');
            if ~strcmpi(buttonConfirmClear, 'Yes')
                return;
            else
                try
                    for i = 1:length(app.tempFiles)
                        delete(strcat(app.tempFiles{i},'.mat'))
                        delete(strcat(app.tempSegFiles{i},'.mat'))
                        delete(strcat(app.tempTrackFiles{i},'.mat'))
                    end
                catch
                end
                delete(app.MitometerUIFigure);
                GUI2;
            end
        end

        % Value changed function: customMinArea
        function customMinAreaValueChanged(app, event)
            app.minArea = app.customMinArea.Value;
            app.customMinArea_3.Value = app.minArea;
        end

        % Value changed function: customMaxArea
        function customMaxAreaValueChanged(app, event)
            app.maxArea = app.customMaxArea.Value;
            app.customMaxArea_3.Value = app.maxArea;
        end

        % Value changed function: customSigVal
        function customSigValValueChanged(app, event)
            app.sig = app.customSigVal.Value;
            app.customSigVal_3.Value = app.sig;
        end

        % Value changed function: customThrVal
        function customThrValValueChanged(app, event)
            app.thr = app.customThrVal.Value;
            app.customThrVal_3.Value = app.thr;
        end

        % Value changed function: gaussCheckbox
        function gaussCheckboxValueChanged(app, event)
            customGauss = app.gaussCheckbox.Value;
            if customGauss
                app.customSigVal.Enable = true;
            else
                app.customSigVal.Enable = false;
            end
        end

        % Value changed function: threshCheckbox
        function threshCheckboxValueChanged(app, event)
            customThresh = app.threshCheckbox.Value;
            if customThresh
                app.customThrVal.Enable = true;
            else
                app.customThrVal.Enable = false;
            end
        end

        % Value changed function: customDistThresh
        function customDistThreshValueChanged(app, event)
            app.distThreshMicrons = app.customDistThresh.Value;
            app.customDistThresh_2.Value = app.distThreshMicrons;
        end

        % Value changed function: customFrameThresh
        function customFrameThreshValueChanged(app, event)
            app.frameThreshSeconds = app.customFrameThresh.Value;
            app.customFrameThresh_2.Value = app.frameThreshSeconds;
        end

        % Value changed function: fisFusCheckbox
        function fisFusCheckboxValueChanged(app, event)
            app.fisFusSkip = app.fisFusCheckbox.Value;
            
        end

        % Value changed function: customWeightCheckbox
        function customWeightCheckboxValueChanged(app, event)
            customWeights = app.customWeightCheckbox.Value;
            if customWeights
                app.customDistWeight.Enable = true;
                app.customAreaWeight.Enable = true;
                app.customMajAxWeight.Enable = true;
                app.customMinAxWeight.Enable = true;
                app.customSolWeight.Enable = true;
                app.customPeriWeight.Enable = true;
                app.customIntWeight.Enable = true;
                app.customZAxWeight.Enable = true;
            else
                app.customDistWeight.Enable = false;
                app.customAreaWeight.Enable = false;
                app.customMajAxWeight.Enable = false;
                app.customMinAxWeight.Enable = false;
                app.customSolWeight.Enable = false;
                app.customPeriWeight.Enable = false;
                app.customIntWeight.Enable = false;
                app.customZAxWeight.Enable = false;
            end
        end

        % Value changed function: customDistWeight
        function customDistWeightValueChanged(app, event)
            app.distWeight = app.customDistWeight.Value;
            
        end

        % Value changed function: customAreaWeight
        function customAreaWeightValueChanged(app, event)
            app.weights(1) = app.customAreaWeight.Value;
            app.customAreaWeight_2.Value = app.weights(1);
        end

        % Value changed function: customMajAxWeight
        function customMajAxWeightValueChanged(app, event)
            app.weights(2) = app.customMajAxWeight.Value;
            app.customMajAxWeight_2.Value = app.weights(2);
        end

        % Value changed function: customMinAxWeight
        function customMinAxWeightValueChanged(app, event)
            app.weights(3) = app.customMinAxWeight.Value;
            app.customMinAxWeight_2.Value = app.weights(3);
        end

        % Value changed function: customSolWeight
        function customSolWeightValueChanged(app, event)
            app.weights(4) = app.customSolWeight.Value;
            app.customSolWeight_2.Value = app.weights(4);
        end

        % Value changed function: customPeriWeight
        function customPeriWeightValueChanged(app, event)
            app.weights(5) = app.customPeriWeight.Value;
            app.customPeriWeight_2.Value = app.weights(5);
        end

        % Value changed function: customIntWeight
        function customIntWeightValueChanged(app, event)
            app.weights(6) = app.customIntWeight.Value;
            app.customIntWeight_2.Value = app.weights(6);
        end

        % Value changed function: customZAxWeight
        function customZAxWeightValueChanged(app, event)
            app.weights(7) = app.customZAxWeight.Value;
            app.customZAxWeight_2.Value = app.weights(7);
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            frameNum = round(event.Value);
            updateSegFigFrame(app,frameNum,app.figNum)
            app.Spinner.Value = frameNum;
        end

        % Selection changed function: SelectedImageButtonGroup
        function SelectedImageButtonGroupSelectionChanged(app, event)
            figNumTemp = app.SelectedImageButtonGroup.SelectedObject;
            app.figNum = str2double(figNumTemp.Tag);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            frameNum = app.Spinner.Value;
            updateSegFigFrame(app,frameNum,app.figNum)
            app.FrameSlider.Value = frameNum;
        end

        % Button pushed function: PlaySeg
        function PlayButtonPushed(app, event)
            numFrames = size(app.segFigHold.ImOG,3);
            
            app.PlaySeg.Text = '...';
            for frameNum = 1:numFrames
                app.Spinner.Value = frameNum;
                updateSegFigFrame(app,frameNum,app.figNum)
                pause(0.2)
            end
            app.PlaySeg.Text = 'Play';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            trackStr = app.DropDown.Value;
            app.keptTracksThresh.Enable = false;
            app.singleTrack.Enable = false;
            app.ObjectCheckBox.Enable = true;
            if strcmp(trackStr,'All tracks')
                trackType = 1;
                app.trackSave = 1;
            elseif strcmp(trackStr,'Kept tracks')
                trackType = 2;
                app.trackSave = 2;
                app.keptTracksThresh.Enable = true;
            elseif strcmp(trackStr,'Confident tracks')
                trackType = 3;
                app.trackSave = 3;
            elseif strcmp(trackStr,'Single track')
                app.singleTrack.Enable = true;
                app.LoadingLabel.Visible = false;
                app.singleTrack.Limits = [1 length(app.holdAll.track)];
                singleTrackValueChanged(app,event)
                return
            elseif strcmp(trackStr,'Perinuclear')
                trackType = 5;
                app.trackSave = 5;
            elseif strcmp(trackStr,'Telenuclear')
                trackType = 6;
                app.trackSave = 6;
            end
            app.trackTypeHold = trackType;
            updateTrackFig(app,trackType,app.selectedIm)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: keptTracksThresh
        function keptTracksThreshValueChanged(app, event)
            app.lengthThresh = app.keptTracksThresh.Value;
            
            updateTrackFig(app,app.trackTypeHold,app.selectedIm)
        end

        % Value changed function: singleTrack
        function singleTrackValueChanged(app, event)
            trackToView = app.singleTrack.Value;
            app.ObjectCheckBox.Enable = true;
            if app.outlineOn
                updateTrackFigObject(app,app.selectedIm,app.holdAll.track(trackToView))
%                 labelImage = plotLables(app.holdAll(app.selectedIm).track,app.holdAll(app.selectedIm).extra.L,trackToView);
%                 addedAll = zeros(size(labelImage,1),size(labelImage,2));
%                 for frameNum = 1:size(labelImage,3)
%                     currIm = labelImage(:,:,frameNum);
%                     addedAll(currIm>0) = frameNum;
%                 end
%                 imagesc(app.TrackFig,addedAll)
%                 app.TrackFig.Colormap = jet;
            else
                updateTrackFigSingle(app,app.selectedIm,trackToView)
            end
        end

        % Value changed function: ObjectCheckBox
        function ObjectCheckBoxValueChanged(app, event)
            app.outlineOn = app.ObjectCheckBox.Value;
            DropDownValueChanged(app,event)
        end

        % Value changed function: ImageList
        function ImageListValueChanged(app, event)
            app.allSelected = app.ImageList.Value;
            app.selectedIm = app.allSelected(1);
        end

        % Button pushed function: SavetifstackButton
        function SavetifstackButtonPushed(app, event)
            
            if app.figNum == 1
                imToSave = app.holdAll.ImOG;
            elseif app.figNum == 2
                imToSave = app.holdAll.ImBgRemoved;
            elseif app.figNum == 3
                if app.is3D
                    for ii = 1:size(app.holdAll.ImOG,4)
                        for i = 1:size(app.holdAll.ImOG,3)
                            labelIm(:,:,i,ii) = rgb2gray(label2rgb(app.holdAll.extra.L{i}(:,:,ii),'hsv','k','shuffle'));
                        end
                    end
                else
                    for i = 1:size(app.holdAll.ImOG,3)
                        labelIm(:,:,i) = rgb2gray(label2rgb(app.holdAll.extra.L{i},'hsv','k','shuffle'));
                    end
                end
                imToSave = labelIm;
            elseif app.figNum == 4
                imToSave = app.holdAll.Im;
            end
            
            defName = app.fileName{app.selectedIm};
            [inputName,savePath,indx] = uiputfile('*.tif','Choose a folder in which to save selected image(s)',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            outputFileName = strcat(savePath,inputName);
            if exist(outputFileName,"file")
                overwritePrompt = sprintf('File already exists. Overwrite?');
                overwriteButton = questdlg(overwritePrompt, 'Overwrite?', 'Overwrite', 'Cancel', 'Overwrite');
                
                if strcmpi(overwriteButton, 'Overwrite')
                    delete(outputFileName)
                    if app.is3D
                        for iii=1:size(imToSave,3)
                            for iv = 1:size(imToSave,4)
                                imwrite(imToSave(:,:,iii,iv), outputFileName, 'WriteMode', 'append',  'Compression','none');
                            end
                        end
                    else
                        for iii=1:size(imToSave,3)
                            imwrite(imToSave(:,:,iii), outputFileName, 'WriteMode', 'append',  'Compression','none');
                        end
                    end
                end
            else
                if app.is3D
                        for iii=1:size(imToSave,3)
                            for iv = 1:size(imToSave,4)
                                imwrite(imToSave(:,:,iii,iv), outputFileName, 'WriteMode', 'append',  'Compression','none');
                            end
                        end
                else
                    for iii=1:size(imToSave,3)
                        imwrite(imToSave(:,:,iii), outputFileName, 'WriteMode', 'append',  'Compression','none');
                    end
                end
            end
        end

        % Callback function
        function SelectedAllPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.allSelected)
                load(app.tempTrackFiles{app.selected(i)})
                saveName = app.fileName{app.allSelected(i)};
                saveVar = track;
                save(strcat(savePath,saveName,"_allTracks",".mat"),"saveVar");
            end
        end

        % Callback function
        function AllAllPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.holdAll)
                load(app.tempTrackFiles{i})
                saveName = app.fileName{i};
                saveVar = track;
                save(strcat(savePath,saveName,"_allTracks",".mat"),"saveVar");
            end
        end

        % Callback function
        function SelectedKeptButtonPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.allSelected)
                saveName = app.fileName{app.allSelected(i)};
                saveVar = app.keptTracks(app.allSelected(i)).track;
                save(strcat(savePath,saveName,"_keptTracks",num2str(app.lengthThresh),".mat"),"saveVar");
            end
        end

        % Callback function
        function AllKeptButtonPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.holdAll)
                saveName = app.fileName{i};
                saveVar = app.keptTracks(i).track;
                save(strcat(savePath,saveName,"_keptTracks",num2str(app.lengthThresh),".mat"),"saveVar");
            end
        end

        % Callback function
        function SelectedPerfButtonPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.allSelected)
                saveName = app.fileName{app.allSelected(i)};
                saveVar = app.holdAll(app.allSelected(i)).allPerfectTracksFinal;
                save(strcat(savePath,saveName,"_perfectTracks",".mat"),"saveVar");
            end
        end

        % Callback function
        function AllPerfButtonPushed(app, event)
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            for i = 1:length(app.holdAll)
                saveName = app.fileName{i};
                saveVar = app.holdAll(i).allPerfectTracksFinal;
                save(strcat(savePath,saveName,"_perfectTracks",".mat"),"saveVar");
            end
        end

        % Button pushed function: CreategroupButton
        function CreategroupButtonPushed(app, event)
            
            app.LoadingLabel.Visible = true;
            pause(0.02)
            
            app.CreategroupButton.Enable = false;
            
            %app.callGroupingApp = groupingApp(app,app.lengthThreshGrouping,app.trackTypeGrouping);

            GroupsDropDownValueChanged(app, event)
            app.LoadingLabel.Visible = false;
        end

        % Close request function: MitometerUIFigure
        function MitometerUIFigureCloseRequest(app, event)
            for i = 1:length(app.tempFiles)
                delete(strcat(app.tempFiles{i},'.mat'))
                delete(strcat(app.tempSegFiles{i},'.mat'))
                delete(strcat(app.tempTrackFiles{i},'.mat'))
            end
            delete(app.callGroupingApp)
            delete(app)
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.allSelectedParams = app.ListBox.Value;
            nope = 0;
            for i = 1:length(app.allSelectedParams)
                if ~app.is3D
                    if strcmp(app.allSelectedParams{i},'ZAxisLength')
                        nope = 1;
                                
                        app.AddtographButton.Enable = false;
                        app.SavetotxtButton.Enable = false;
                    end
                end
            end
            if app.GroupsDropDown.Enable && ~nope
                app.AddtographButton.Enable = true;
                app.SavetotxtButton.Enable = true;
            end
        end

        % Button pushed function: AddtographButton
        function AddtographButtonPushed(app, event)
            if ~iscell(app.allSelectedParams)
                app.allSelectedParams = {app.allSelectedParams};
            end
            app.selectedGroup = app.GroupsDropDown.Value;

            for i = 1:length(app.selectedGroup)
                if app.is3D
                    medianVals(i).Area = nanmedian(app.selectedGroup(i).Volume);
                    medianVals(i).Perimeter = nanmedian(app.selectedGroup(i).SurfaceArea);
                    medianVals(i).ZAxisLength = nanmedian(app.selectedGroup(i).ZAxisLength);
                else
                    medianVals(i).Area = nanmedian(app.selectedGroup(i).Area);
                    medianVals(i).Perimeter = nanmedian(app.selectedGroup(i).Perimeter);
                    medianVals(i).zAxisLength = 0;
                    
                end
                medianVals(i).MajorAxisLength = nanmedian(app.selectedGroup(i).MajorAxisLength);
                medianVals(i).MinorAxisLength = nanmedian(app.selectedGroup(i).MinorAxisLength);
                medianVals(i).Solidity = nanmedian(app.selectedGroup(i).Solidity);
                medianVals(i).MeanIntensity = nanmedian(app.selectedGroup(i).MeanIntensity);
                medianVals(i).fission = nnz([app.selectedGroup.fission])/length(app.selectedGroup);
                medianVals(i).fusion = nnz([app.selectedGroup.fusion])/length(app.selectedGroup);
                medianVals(i).speed = nanmedian(app.selectedGroup(i).speed);
                medianVals(i).distance = nanmedian(app.selectedGroup(i).distance);
                medianVals(i).displacement = nanmedian(app.selectedGroup(i).displacement);
                medianVals(i).velocity = nanmedian(app.selectedGroup(i).velocity);
            end
            
            app.graphGroups = [app.graphGroups,{[medianVals.(app.allSelectedParams{1})]}];
                        
            updateGraph(app)
        end

        % Value changed function: GroupsDropDown
        function GroupsDropDownValueChanged(app, event)
            clear app.GroupsDropDown.Value
            app.lengthThreshGrouping = 1;
            app.trackTypeGrouping = 'Perfect only';
            createGroup(app,'tmp');
            app.selectedGroup = app.GroupsDropDown.ItemsData{end};
            app.allSelectedParams = app.ListBox.Value;
            b = app.selectedGroup;
            c = app.allSelectedParams;

        end

        % Button pushed function: SavetotxtButton
        function SavetotxtButtonPushed(app, event)
            
            selStruc = app.selectedGroup;
            app.selectedGroup = app.GroupsDropDown.ItemsData{end};
            selg = app.selectedGroup;
            maxFrames = max([app.selectedGroup.frame]);
            frameArray = 1:maxFrames;
            numTracks = length(selStruc);
            fnames = {'fission','Area'};
            
            
            a = app.imNumT;
            cd C:/Users/Ian/Desktop/old_desktop/mito/mitometrics/out
            %o = holdAllTemp.fileName;
            o = char(app.fileName{app.imNumT});
            ov = strsplit(o,'.');
            fnm = strcat(ov{1},ov{2});
            mkdir(fnm)
            savePath = fnm;%uigetdir(app.path,'Select a folder to save your files. Text files will be named "date and time"+"_group name"+"_parameter name"');
            for i = 1:length(app.GroupsDropDown.Items)
                if isequaln(app.GroupsDropDown.ItemsData{i},app.selectedGroup)
                    groupName = app.GroupsDropDown.Items{i};
                end
            end
            
            
            for i = 1:length(fnames)
                out = nan(numTracks,maxFrames);
                if app.is3D 
                    if strcmp(fnames{i},'Area')
                        fnames{i} = 'Volume';
                    elseif strcmp(fnames{i},'Perimeter')
                        fnames{i} = 'SurfaceArea';
                    end
                end
                for j = 1:numTracks
                    [~,loc] = ismember(selStruc(j).frame,frameArray);
                    out(j,(loc)) = [selStruc(j).(fnames{i})];
                end
                strcat(savePath,datestr(now,'yyyymmdd_'),groupName,'_',fnames{i},'.txt');
                writematrix(out,strcat(savePath,"/",datestr(now,'yyyymmdd_'),groupName,'_',fnames{i},'.txt'))
            end
        end

        % Value changed function: LogscaleCheckBox
        function LogscaleCheckBoxValueChanged(app, event)
            app.logOn = app.LogscaleCheckBox.Value;
            updateGraph(app)
        end

        % Button pushed function: ResetgraphButton
        function ResetgraphButtonPushed(app, event)
            cla(app.UIAxes,'reset')
            app.graphGroups = {};
        end

        % Selection changed function: GraphtypeButtonGroup
        function GraphtypeButtonGroupSelectionChanged(app, event)
            updateGraph(app)
        end

        % Value changing function: ZPlaneSlider
        function ZPlaneSliderValueChanging(app, event)
            app.zPlaneSelected = round(event.Value);
            app.Spinner_2.Value = app.zPlaneSelected;
            updateSegFigs(app,1,app.figNum)

        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            app.zPlaneSelected = app.Spinner_2.Value;
            app.ZPlaneSlider.Value = app.zPlaneSelected;
            updateSegFigs(app,1,app.figNum)

        end

        % Value changed function: ZAspectRatioSpinner
        function ZAspectRatioSpinnerValueChanged(app, event)
            app.zAspectRatio = app.ZAspectRatioSpinner.Value;
            updateTrackFig(app,1,app.figNum)
        end

        % Button pushed function: SelecthighlightedButton
        function SelecthighlightedButtonPushed(app, event)
            ab = app.selectedIm;
            l = app.tempFiles{ab}
            seg = app.tempSegFiles{ab}
            load(char(l))
            load(char(seg))
            load(app.tempFiles{app.selectedIm})
            load(app.tempSegFiles{app.selectedIm})
             
            app.holdAll = holdAllTemp;
            app.holdAll.ImOG = holdOG.ImAll;
            
            updateSegFigs(app,app.selectedIm,1)
            updateTrackFig(app,1,app.selectedIm)
            
            DropDownValueChanged(app,event)
        end

        % Button pushed function: SelectedButton
        function SelectedButtonPushed(app, event)
            app.LoadingLabel.Visible = true;
            pause(0.02)

            app.keptTracksThresh.Enable = true;
            
            lenThresh = app.keptTracksThresh.Value;
            
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            
            for numSelected = 1:length(app.allSelected)
                load(app.tempTrackFiles{app.allSelected(numSelected)})
                if app.trackSave == 1
                    appendText = "_allTracks_thresh";
                    [trackList,~] = trackLengthThreshold(track,lenThresh);
                elseif app.trackSave == 3
                    appendText = "_confidentTracks_thresh";
                    if app.is3D
                        [~,~,perfectTrackIdxs] = getTrackingWeights3d(track);
                    else
                        [~,~,perfectTrackIdxs] = getTrackingWeights(track);
                    end
                    [trackList,~] = trackLengthThreshold(track,lenThresh,perfectTrackIdxs);
                elseif app.trackSave == 5
                    appendText = "_perinuclearTracks_thresh";
                    [~,centerIdx] = edgeCenterMito(track,mitoCoM,max([track.frame]),app.is3D);
                    [trackList,~] = trackLengthThreshold(track,lenThresh,centerIdx);
                elseif app.trackSave == 6
                    appendText = "_telenuclearTracks_thresh";
                    [edgeIdx,~] = edgeCenterMito(track,mitoCoM,max([track.frame]),app.is3D);
                    [trackList,~] = trackLengthThreshold(track,lenThresh,edgeIdx);
                end
                
                saveName = app.fileName{app.allSelected(numSelected)};
                save(strcat(savePath,saveName,appendText,num2str(lenThresh),".mat"),"trackList");
            end
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            app.LoadingLabel.Visible = true;
            pause(0.02)

            app.keptTracksThresh.Enable = true;
            
            lenThresh = app.keptTracksThresh.Value;
            
            defName = " ";
            [~,savePath,indx] = uiputfile('*.mat','Choose a folder in which to save selected track(s). Default name will be used.',defName);
            if indx == 0
                sprintf('User canceled.')
                return
            end
            
            for numSelected = 1:length(app.tempTrackFiles)
                load(app.tempTrackFiles{numSelected})
                if app.trackSave == 1
                    appendText = "_allTracks_thresh";
                    [trackList,~] = trackLengthThreshold(track,lenThresh);
                elseif app.trackSave == 3
                    appendText = "_confidentTracks_thresh";
                    if app.is3D
                        [~,~,perfectTrackIdxs] = getTrackingWeights3d(track);
                    else
                        [~,~,perfectTrackIdxs] = getTrackingWeights(track);
                    end
                    [trackList,~] = trackLengthThreshold(track,lenThresh,perfectTrackIdxs);
                elseif app.trackSave == 5
                    appendText = "_perinuclearTracks_thresh";
                    [~,centerIdx] = edgeCenterMito(track,mitoCoM,max([track.frame]),app.is3D);
                    [trackList,~] = trackLengthThreshold(track,lenThresh,centerIdx);
                elseif app.trackSave == 6
                    appendText = "_telenuclearTracks_thresh";
                    [edgeIdx,~] = edgeCenterMito(track,mitoCoM,max([track.frame]),app.is3D);
                    [trackList,~] = trackLengthThreshold(track,lenThresh,edgeIdx);
                end
                saveName = app.fileName{numSelected};
                save(strcat(savePath,saveName,appendText,num2str(lenThresh),".mat"),"trackList");
            end
        end

        % Button pushed function: RemovetracksButton
        function RemovetracksButtonPushed(app, event)
            app.CancelButton.Enable = true;
            app.RemovetracksButton.Enable = false;
            
            h = drawfreehand(app.TrackFig);
            app.LoadingLabel.Visible = true;
            selectedROI = h.Position;
            
            if size(selectedROI,1) == 1
                testing = zeros(size(app.holdAll.ImOG(:,:,1)));
                testing(round(selectedROI(2)),round(selectedROI(1))) = 1;
            else
                testing = h.createMask;
            end
            delete(h)
            roiPixels = regionprops(testing,'PixelIdxList');
            removeIdx = roiPixels.PixelIdxList;
          
            keepTrack = [];

            for i = 1:length(app.holdAll.track)
                allIDX = [];
                if app.is3D
                    if ~iscell(app.holdAll.track(i).VoxelIdxList)
                        app.holdAll.track(i).VoxelIdxList = {app.holdAll.track(i).VoxelIdxList};
                    end
                    for j = 1:length(app.holdAll.track(i).VoxelIdxList)    
                        allIDX = [allIDX;app.holdAll.track(i).VoxelIdxList{j}];
                    end 
                else
                    if ~iscell(app.holdAll.track(i).PixelIdxList)
                        app.holdAll.track(i).PixelIdxList = {app.holdAll.track(i).PixelIdxList};
                    end
                    for j = 1:length(app.holdAll.track(i).PixelIdxList)    
                        allIDX = [allIDX;app.holdAll.track(i).PixelIdxList{j}];
                    end 
                end
                allIDX = unique(allIDX);
                remove = false;
                for IdxNum = allIDX'
                    if any(IdxNum==removeIdx)
                        remove = true;
                        break 
                    end
                end
                if ~remove
                    keepTrack = [keepTrack,i];
                end
            end
            app.ConfirmButton.Enable = true;
            app.tempTrack = app.holdAll.track;
            [app.holdAll.track,~] = trackLengthThreshold(app.tempTrack,app.keptTracksThresh.Value,keepTrack);
            updateTrackFig(app,app.trackTypeHold,1)
            app.LoadingLabel.Visible = false;

        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.RemovetracksButton.Enable = true;
            app.CancelButton.Enable = false;
            app.ConfirmButton.Enable = false;
            
            delete(strcat(app.tempSegFiles{app.selectedIm},'.mat'))
            delete(strcat(app.tempTrackFiles{app.selectedIm},'.mat'))
            
            holdAllTemp = app.holdAll;
            
            track = app.holdAll.track;
            mitoCoM = app.holdAll.mitoCoM;
            
            % Save in a temporary file
            save(app.tempSegFiles{app.selectedIm},'holdAllTemp');
                
            % Save just track in a temporary file
            save(app.tempTrackFiles{app.selectedIm},'track','mitoCoM');
            

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.RemovetracksButton.Enable = true;
            app.CancelButton.Enable = false;
            app.ConfirmButton.Enable = false;
            
            app.holdAll.track = app.tempTrack;
            updateTrackFig(app,app.trackTypeHold,1)
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            allSelectedParamsSingle = app.ListBox_2.Value;
            if ~iscell(allSelectedParamsSingle)
                allSelectedParamsSingle = {allSelectedParamsSingle};
            end
            nope = 0;
            for i = 1:length(allSelectedParamsSingle)
                if ~app.is3D
                    if strcmp(allSelectedParamsSingle{i},'ZAxisLength')                                
                        app.SavetotxtButton_2.Enable = false;
                        nope=1;
                    end
                end
            end
            if ~nope
                for i = 1:length(app.oneFileDynamics.speed)
                    holdBoth(i).speed = app.oneFileDynamics.speed{i};
                    holdBoth(i).distance = app.oneFileDynamics.distance{i};
                    holdBoth(i).displacement = app.oneFileDynamics.displacement{i};
                    holdBoth(i).velocity = app.oneFileDynamics.velocity{i};
                end
                for i = 1:length(app.oneFileTrack)
                    if app.is3D
                        medianVals(i).Area = nanmedian(app.oneFileTrack(i).Volume);
                        medianVals(i).Perimeter = nanmedian(app.oneFileTrack(i).SurfaceArea);
                        medianVals(i).ZAxisLength = nanmedian(app.oneFileTrack(i).ZAxisLength);
                    else
                        medianVals(i).Area = nanmedian(app.oneFileTrack(i).Area);
                        medianVals(i).Perimeter = nanmedian(app.oneFileTrack(i).Perimeter);
                        medianVals(i).zAxisLength = 0;
                    end
                    medianVals(i).MajorAxisLength = nanmedian(app.oneFileTrack(i).MajorAxisLength);
                    medianVals(i).MinorAxisLength = nanmedian(app.oneFileTrack(i).MinorAxisLength);
                    medianVals(i).Solidity = nanmedian(app.oneFileTrack(i).Solidity);
                    medianVals(i).MeanIntensity = nanmedian(app.oneFileTrack(i).MeanIntensity);
                    medianVals(i).fission = nnz([app.oneFileTrack.fission])/length(app.oneFileTrack);
                    medianVals(i).fusion = nnz([app.oneFileTrack.fusion])/length(app.oneFileTrack);
                    medianVals(i).speed = nanmedian(holdBoth(i).speed);
                    medianVals(i).distance = nanmedian(holdBoth(i).distance);
                    medianVals(i).displacement = nanmedian(holdBoth(i).displacement);
                    medianVals(i).velocity = nanmedian(holdBoth(i).velocity);
                end
                h = histogram(app.UIAxes_2,[medianVals.(allSelectedParamsSingle{1})],round(length(app.oneFileTrack)/2),'Normalization','probability');
                app.UIAxes_2.YLim = [0,max(h.Values)];
                app.UIAxes_2.XLim = [min(h.BinEdges),max(h.BinEdges)];

                app.oneFileParameter = allSelectedParamsSingle;
                app.SavetotxtButton_2.Enable = true;

            end
        end

        % Button pushed function: SavetotxtButton_2
        function SavetotxtButton_2Pushed(app, event)
            selStruc = app.oneFileTrack;
            app.selectedGroup = app.oneFileTrack;
            maxFrames = max([app.selectedGroup.frame]);
            frameArray = 1:maxFrames;
            numTracks = length(selStruc);
            fnames = app.oneFileParameter;
            
            savePath = uigetdir(app.path,'Select a folder to save your files. Text files will be named "date and time"+"_group name"+"_parameter name"');
            groupName = app.holdAll.fileName;
            
            for i = 1:length(fnames)

                out = nan(numTracks,maxFrames);
                if app.is3D 
                    if strcmp(fnames{i},'Area')
                        fnames{i} = 'Volume';
                    elseif strcmp(fnames{i},'Perimeter')
                        fnames{i} = 'SurfaceArea';
                    end
                end
                for j = 1:numTracks
                    [~,loc] = ismember(selStruc(j).frame,frameArray);
                    try
                        out(j,(loc)) = [selStruc(j).(fnames{i})];
                    catch
                        out(j,(loc)) = [app.oneFileDynamics.(fnames{i}){j}];
                    end
                end
                strcat(savePath,datestr(now,'yyyymmdd_'),groupName,'_',fnames{i},'.txt');
                writematrix(out,strcat(savePath,"/",datestr(now,'yyyymmdd_'),groupName,'_',fnames{i},'.txt'))
            end
        end

        % Value changed function: customMinArea_3
        function customMinArea_3ValueChanged(app, event)
            value = app.customMinArea_3.Value;
            app.SetButton.Enable = true;
        end

        % Value changed function: customMaxArea_3
        function customMaxArea_3ValueChanged(app, event)
            value = app.customMaxArea_3.Value;
            app.SetButton_2.Enable = true;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.customMinArea.Value = app.customMinArea_3.Value;
            app.SetButton.Enable = false;
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
            app.customMaxArea.Value = app.customMaxArea_3.Value;
            app.SetButton_2.Enable = false;
        end

        % Button pushed function: SetButton_3
        function SetButton_3Pushed(app, event)
            app.customSigVal.Value = app.customSigVal_3.Value;
            app.SetButton_3.Enable = false;
            app.customSigVal.Enable = true;
            app.gaussCheckbox.Value = true;
        end

        % Button pushed function: SetButton_4
        function SetButton_4Pushed(app, event)
            app.customThrVal.Value = app.customThrVal_3.Value;
            app.SetButton_4.Enable = false;
            app.customThrVal.Enable = true;
            app.threshCheckbox.Value = true;
        end

        % Button pushed function: SetButton_7
        function SetButton_7Pushed(app, event)
            app.customAreaWeight.Value = app.customAreaWeight_2.Value;
            app.customMajAxWeight.Value = app.customMajAxWeight_2.Value;
            app.customMinAxWeight.Value = app.customMinAxWeight_2.Value;
            app.customSolWeight.Value = app.customSolWeight_2.Value;
            app.customPeriWeight.Value = app.customPeriWeight_2.Value;
            app.customIntWeight.Value = app.customIntWeight_2.Value;
            app.customZAxWeight.Value = app.customZAxWeight_2.Value;
            app.customAreaWeight.Enable = true;
            app.customMajAxWeight.Enable = true;
            app.customMinAxWeight.Enable = true;
            app.customSolWeight.Enable = true;
            app.customPeriWeight.Enable = true;
            app.customIntWeight.Enable = true;
            app.customZAxWeight.Enable = true;
            app.SetButton_7.Enable = false;
            app.threshCheckbox.Value = true;
            app.customWeightCheckbox.Value = true;
        end

        % Button pushed function: SetButton_5
        function SetButton_5Pushed(app, event)
            app.customDistThresh.Value = app.customDistThresh_2.Value;
            app.SetButton_5.Enable = false;
        end

        % Button pushed function: SetButton_6
        function SetButton_6Pushed(app, event)
            app.customFrameThresh.Value = app.customFrameThresh_2.Value;
            app.SetButton_6.Enable = false;

        end

        % Value changed function: customSigVal_3
        function customSigVal_3ValueChanged(app, event)
            value = app.customSigVal_3.Value;
            app.SetButton_3.Enable = true;

        end

        % Value changed function: customThrVal_3
        function customThrVal_3ValueChanged(app, event)
            value = app.customThrVal_3.Value;
            app.SetButton_4.Enable = true;
        end

        % Value changed function: customDistThresh_2
        function customDistThresh_2ValueChanged(app, event)
            value = app.customDistThresh_2.Value;
            app.SetButton_5.Enable = true;
        end

        % Value changed function: customFrameThresh_2
        function customFrameThresh_2ValueChanged(app, event)
            value = app.customFrameThresh_2.Value;
            app.SetButton_6.Enable = true;
        end

        % Value changed function: customAreaWeight_2
        function customAreaWeight_2ValueChanged(app, event)
            value = app.customAreaWeight_2.Value;
            app.SetButton_7.Enable = true;
            app.tsWeight(1) = value;
        end

        % Value changed function: customSolWeight_2
        function customSolWeight_2ValueChanged(app, event)
            value = app.customSolWeight_2.Value;
            app.SetButton_7.Enable = true;
            app.tsWeight(4) = value;
        end

        % Value changed function: customMinAxWeight_2
        function customMinAxWeight_2ValueChanged(app, event)
            value = app.customMinAxWeight_2.Value;
            app.SetButton_7.Enable = true;
            app.tsWeight(3) = value;
        end

        % Value changed function: customMajAxWeight_2
        function customMajAxWeight_2ValueChanged(app, event)
            value = app.customMajAxWeight_2.Value;
            app.SetButton_7.Enable = true;
            app.tsWeight(2) = value;
        end

        % Value changed function: customPeriWeight_2
        function customPeriWeight_2ValueChanged(app, event)
            value = app.customPeriWeight_2.Value;
            app.SetButton_7.Enable = true;
            app.tsWeight(5) = value;
        end

        % Value changed function: customIntWeight_2
        function customIntWeight_2ValueChanged(app, event)
            value = app.customIntWeight_2.Value;
            app.SetButton_7.Enable = true;
            app.tsWeight(6) = value;
        end

        % Value changed function: customZAxWeight_2
        function customZAxWeight_2ValueChanged(app, event)
            value = app.customZAxWeight_2.Value;
            app.SetButton_7.Enable = true;
            app.tsWeight(7) = value;
        end

        % Value changed function: gaussCheckbox_3
        function gaussCheckbox_3ValueChanged(app, event)
            value = app.gaussCheckbox_3.Value;
            app.customSigVal_3.Enable = value;
        end

        % Value changed function: threshCheckbox_3
        function threshCheckbox_3ValueChanged(app, event)
            value = app.threshCheckbox_3.Value;
            app.customThrVal_3.Enable = value;
            
        end

        % Value changed function: customWeightCheckbox_2
        function customWeightCheckbox_2ValueChanged(app, event)
            value = app.customWeightCheckbox_2.Value;
            app.customAreaWeight_2.Enable = value;
            app.customMajAxWeight_2.Enable = value;
            app.customMinAxWeight_2.Enable = value;
            app.customSolWeight_2.Enable = value;
            app.customPeriWeight_2.Enable = value;
            app.customIntWeight_2.Enable = value;
            app.customZAxWeight_2.Enable = value;
            
            app.tsWeight(1) = app.customAreaWeight_2.Value;
            app.tsWeight(2) = app.customMajAxWeight_2.Value;
            app.tsWeight(3) = app.customMinAxWeight_2.Value;
            app.tsWeight(4) = app.customSolWeight_2.Value;
            app.tsWeight(5) = app.customPeriWeight_2.Value;
            app.tsWeight(6) = app.customIntWeight_2.Value;
            app.tsWeight(7) = app.customZAxWeight_2.Value;

        end

        % Button pushed function: Upload2DButton
        function Upload2DButtonPushed(app, event)
            [app.tsMicronPerPixel, app.tsSecondsPerFrame] = start2D();
            [app.tsImOG,~,~,~] = openTifs(); %open all selected images
            app.tsMinArea = max(4*app.tsMicronPerPixel,app.customMinArea_3.Value);
            [app.tsIm{1},~,~] = diffuseBgRemove(app.tsImOG{1},(app.tsMinArea)/app.tsMicronPerPixel,(1)/app.tsMicronPerPixel);
            app.tsImShow = app.tsIm;
            updateThreshSegFig(app,app.tsImShow,1,0);
            app.GoButton.Enable = true;
        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            frameNum = app.Spinner_3.Value;
            updateThreshSegFigFrame(app,frameNum,0)
            app.FrameSlider_2.Value = frameNum;
        end

        % Button pushed function: PlaySeg_2
        function PlaySeg_2ButtonPushed(app, event)
            numFrames = size(app.tsIm{1},3);
            
            app.PlaySeg_2.Text = '...';
            for frameNum = 1:numFrames
                app.Spinner_3.Value = frameNum;
                updateThreshSegFigFrame(app,frameNum,0)
                pause(0.2)
            end
            app.PlaySeg_2.Text = 'Play';
        end

        % Value changing function: FrameSlider_2
        function FrameSlider_2ValueChanging(app, event)
            frameNum = round(event.Value);
            updateThreshSegFigFrame(app,frameNum,0)
            app.Spinner_3.Value = frameNum;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            if size(app.tsIm{1},4)>1
                ts3D = 1;
            else
                ts3D = 0;
            end
            
            if app.gaussCheckbox_3.Value && app.threshCheckbox_3.Value
                tsSig = app.customSigVal_3.Value;
                tsThr = app.customThrVal_3.Value;
            elseif app.gaussCheckbox_3.Value
                tsSig = app.customSigVal_3.Value;
                if ~ts3D
                    [~,tsThr,~] = optimizeSigmaThresh(app.tsIm{1});
                else
                    [~,tsThr,~] = optimizeSigmaThresh(app.tsIm{1}(:,:,:,find(app.tsMeanInt==max(app.tsMeanInt))));
                end
            elseif app.threshCheckbox_3.Value
                tsThr = app.customThrVal_3.Value;
                if ~ts3D
                    [tsSig,~,~] = optimizeSigmaThresh(app.tsIm{1});
                else
                    [tsSig,~,~] = optimizeSigmaThresh(app.tsIm{1}(:,:,:,find(app.tsMeanInt==max(app.tsMeanInt))));
                end
            else
                if ~ts3D
                    [tsSig,tsThr,~] = optimizeSigmaThresh(app.tsIm{1});
                else
                    [tsSig,tsThr,~] = optimizeSigmaThresh(app.tsIm{1}(:,:,:,find(app.tsMeanInt==max(app.tsMeanInt))));
                end
            end
            
            if ~ts3D
                ImGaussFiltered = gaussFilter(app.tsIm{1},tsSig);
                ImMask = thresholdImage(ImGaussFiltered,tsThr);
                ImMaskThresholded = areaThreshold(ImMask,app.tsMinArea/(app.tsMicronPerPixel^2),app.customMaxArea_3.Value/(app.tsMicronPerPixel^2));
                app.tsImShow = {ImMaskThresholded.*app.tsImOG{1}};
            else
                clear temp
                ImGaussFiltered = zeros(size(app.tsImOG{1}),'like',app.tsImOG{1});
                ImMask = zeros(size(app.tsImOG{1}),'like',app.tsImOG{1});
                ImMaskThresholded = zeros(size(app.tsImOG{1}),'like',app.tsImOG{1});
                ImMasked = zeros(size(app.tsImOG{1}),'like',app.tsImOG{1});
                
                numStacks = size(app.tsImOG{1},4);
                
                for stackNum = 1:numStacks
                    temp(:,:,:) = app.tsIm{1}(:,:,:,stackNum);
                    
                    % Gaussian filter for low-pass filter and smooth discontinuities.
                    ImGaussFiltered(:,:,:,stackNum) = gaussFilter(temp,tsSig);
                    
                    % Threshold image to remove adjacent mitochondria connections and bg.
                    ImMask(:,:,:,stackNum) = thresholdImage(ImGaussFiltered(:,:,:,stackNum),tsThr);
                    
                    % Area threshold and apply mask
                    ImMaskThresholded(:,:,:,stackNum) = uint8(areaThreshold(ImMask(:,:,:,stackNum),app.tsMinArea/(app.tsMicronPerPixel^2),app.customMaxArea_3.Value/app.tsMicronPerPixel^2));
                    ImMasked(:,:,:,stackNum) = app.tsImOG{1}(:,:,:,stackNum).*ImMaskThresholded(:,:,:,stackNum);
                end
                clear temp
                threshIm = zeros(size(app.tsImOG{1}),'like',app.tsImOG{1});
                numFrames = size(ImMasked,3);
                for frameNum = 1:numFrames
                    temp(:,:,:) = ImMasked(:,:,frameNum,:);
                    CCPreThresh = bwconncomp(temp);
                    statsPreThresh = regionprops3(CCPreThresh);
                    
                    %Remove components existing in only one plane
                    keptComponents = find(mod(statsPreThresh.Centroid(:,3),1)~=0);
                    
                    %Make a new image with only kept components
                    threshIm(:,:,frameNum,:) = ismember(labelmatrix(CCPreThresh),keptComponents);
                end                
                app.tsImShow = {ImMasked.*uint8(threshIm)};
            end
            
            app.GoButton_2.Enable = true;
            updateThreshSegFig(app,app.tsImShow,1,ts3D);
        end

        % Button pushed function: GoButton_2
        function GoButton_2Pushed(app, event)
                tsVel = app.customDistThresh_2.Value;
                tsTime = app.customFrameThresh_2.Value;
                
                if size(app.tsImShow{1},4)>1
                    ts3D = 1;
                else
                    ts3D = 0;
                end
                
                if isinf(tsTime)
                    tsTime = size(app.tsImShow{1},3);
                end
                
                if ~app.customWeightCheckbox_2.Value
                    if ~ts3D
                        weightsPrelim = ones(1,6)*1/6;
                        trackPrelim = trackMitochondria(app.tsImShow{1},app.tsImOG{1},weightsPrelim,app.tsMicronPerPixel,app.tsSecondsPerFrame,tsVel,tsTime,0,1);
                        % Get the weights
                        [app.tsWeight,~] = getTrackingWeights(trackPrelim);
                        if sum(isnan(app.tsWeight))
                            app.tsWeight = weightsPrelim;
                        end
                    else
                        weightsPrelim = ones(1,7)*1/7;
                        trackPrelim = trackMitochondria3d(app.tsImShow{1},app.tsImOG{1},weightsPrelim,app.tsMicronPerPixel,app.tsSecondsPerFrame,tsVel,tsTime,app.tsZDistance,0,1);
                        % Get the weights
                        [app.tsWeight,~] = getTrackingWeights3d(trackPrelim);
                        if sum(isnan(app.tsWeight))
                            app.tsWeight = weightsPrelim;
                        end
                    end
                end
                % Real tracking with new weights
                if ~app.fisFusCheckbox_2.Value %check fission and fusion too
                    if ~ts3D
                        [track,~,~] = trackMitochondria(app.tsImShow{1},app.tsImOG{1},app.tsWeight(1:6),app.tsMicronPerPixel,app.tsSecondsPerFrame,tsVel,tsTime);
                    else
                        [track,~,~] = trackMitochondria3d(app.tsImShow{1},app.tsImOG{1},app.tsWeight(1:7),app.tsMicronPerPixel,app.tsSecondsPerFrame,tsVel,tsTime,app.tsZDistance);
                    end
                else
                    if ~ts3D
                        [track,~,~] = trackMitochondria(app.tsImShow{1},app.tsImOG{1},app.tsWeight(1:6),app.tsMicronPerPixel,app.tsSecondsPerFrame,tsVel,tsTime,0,1);
                    else
                        [track,~,~] = trackMitochondria3d(app.tsImShow{1},app.tsImOG{1},app.tsWeight(1:7),app.tsMicronPerPixel,app.tsSecondsPerFrame,tsVel,tsTime,app.tsZDistance,0,1);
                    end
                end
                
                imagesc(app.TrackFig_2,255-app.tsImOG{1}(:,:,1,1))
                app.TrackFig_2.Colormap = gray;
                app.TrackFig_2.XLim = [0,size(app.tsImOG{1},2)];
                app.TrackFig_2.YLim = [0,size(app.tsImOG{1},1)];
                hold(app.TrackFig_2,"on")
                
                for i = 1:length(track)
                    tsPlotWeightedCentroidApp(app,track(i),ts3D)
                    tsPlotFissionEventsApp(app,track,i,ts3D)
                    tsPlotFusionEventsApp(app,track,i,ts3D)
                end
                
                hold(app.TrackFig_2,"off")
                
        end

        % Button pushed function: Upload3DButton
        function Upload3DButtonPushed(app, event)
            addpath(strcat(pwd,'/3D'));
            
            [app.tsMicronPerPixel, app.tsSecondsPerFrame, app.tsZPlaneNum, app.tsZDistance] = start3D(true);
            [app.tsImOG,~,~,~] = openTifs(app.tsZPlaneNum); %open all selected images
            app.tsMinArea = max(4*app.tsMicronPerPixel,app.customMinArea_3.Value);
            
            app.tsZRatio = app.tsZDistance/app.tsMicronPerPixel;   

            numStacks = size(app.tsImOG{1},4);
            
            clear temp
            app.tsIm = {zeros(size(app.tsImOG{1}),'like',app.tsImOG{1})};
            app.tsMeanInt = zeros(1,numStacks);

            for stackNum = 1:numStacks
                app.Upload3DButton.Text = strcat("Plane ",num2str(stackNum)," of ",num2str(numStacks));
                temp(:,:,:) = app.tsImOG{1}(:,:,:,stackNum);
                [app.tsIm{1}(:,:,:,stackNum),~,~] = diffuseBgRemove(temp,app.tsMinArea/app.tsMicronPerPixel,1/app.tsMicronPerPixel);
                app.tsMeanInt(stackNum) = mean(mean(mean(app.tsIm{1}(:,:,1:min(10,size(app.tsIm{1},3)),stackNum))));
            end
            app.Upload3DButton.Text = "Upload 3D";
            
            app.tsImShow = app.tsIm;
            updateThreshSegFig(app,app.tsImShow,1,1);

            app.GoButton.Enable = true;
        end

        % Value changed function: Spinner_4
        function Spinner_4ValueChanged(app, event)
            app.tsZPlane = app.Spinner_4.Value;
            app.ZPlaneSlider_2.Value = app.tsZPlane;

            updateThreshSegFigFrame(app,1,1)
        end

        % Value changing function: ZPlaneSlider_2
        function ZPlaneSlider_2ValueChanging(app, event)
            app.tsZPlane = round(event.Value);
            app.Spinner_4.Value = app.tsZPlane;
            updateThreshSegFigFrame(app,1,1)
        end

        % Button pushed function: OpenFIJIButton
        function OpenFIJIButtonPushed(app, event)
            openIJ()
        end

        % Button pushed function: IJButton
        function IJButtonPushed(app, event)
            evalin('base', 'IJM.getDatasetAs("im")');
            try
                im = evalin('base','im');
            catch
                sprintf("No open image found")
                return
            end
            flipIm = flipIJIm(im);
            app.IJon = true;
            app.IJIm = flipIm;
            app.Start2DButton.Enable = false;
            app.IJButton_2.Enable = false;
            Start2DButtonPushed(app, event)
        end

        % Button pushed function: IJButton_2
        function IJButton_2Pushed(app, event)
            evalin('base', 'IJM.getDatasetAs("im")');
            try
                im = evalin('base','im');
            catch
                sprintf("No open image found")
                return
            end
            flipIm = flipIJIm(im);
            app.IJon = true;
            app.IJIm = flipIm;
            app.Start3DButton.Enable = false;
            app.IJButton.Enable = false;
            Start3DButtonPushed(app, event)
        end

        % Button pushed function: IJButton_3
        function IJButton_3Pushed(app, event)
            if app.figNum == 1
                imToSave = app.holdAll.ImOG;
            elseif app.figNum == 2
                imToSave = app.holdAll.ImBgRemoved;
            elseif app.figNum == 3
                if app.is3D
                    for ii = 1:size(app.holdAll.ImOG,4)
                        for i = 1:size(app.holdAll.ImOG,3)
                            labelIm(:,:,i,ii) = rgb2gray(label2rgb(app.holdAll.extra.L{i}(:,:,ii),'hsv','k','shuffle'));
                        end
                    end
                else
                    for i = 1:size(app.holdAll.ImOG,3)
                        labelIm(:,:,i) = rgb2gray(label2rgb(app.holdAll.extra.L{i},'hsv','k','shuffle'));
                    end
                end
                imToSave = labelIm;
            elseif app.figNum == 4
                imToSave = app.holdAll.Im;
            end
            
            openInFIJI(app,imToSave)
        end

        % Callback function
        function IJButton_4Pushed(app, event)
            F = app.TrackFig;
            im = frame2im(F);
            openInFIJI(app,im)
        end

        % Button pushed function: IJButton_5
        function IJButton_5Pushed(app, event)
            openInFIJI(app,app.tsImShow{1})
        end

        % Callback function
        function IJButton_6Pushed(app, event)
            F = getframe(app.TrackFig_2);
            im = frame2im(F);
            openInFIJI(app,im)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MitometerUIFigure = uifigure('Visible', 'off');
            app.MitometerUIFigure.Name = 'Mitometer';
            app.MitometerUIFigure.CloseRequestFcn = createCallbackFcn(app, @MitometerUIFigureCloseRequest, true);
            app.DebugButton = uibutton(app.MitometerUIFigure, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Visible = 'off';
            app.DebugButton.Text = 'Debug';
            app.TabGroup = uitabgroup(app.MitometerUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Start2DButton = uibutton(app.HomeTab, 'push');
            app.Start2DButton.ButtonPushedFcn = createCallbackFcn(app, @Start2DButtonPushed, true);
            app.Start2DButton.Text = 'Start 2D';
            app.Start3DButton = uibutton(app.HomeTab, 'push');
            app.Start3DButton.ButtonPushedFcn = createCallbackFcn(app, @Start3DButtonPushed, true);
            app.Start3DButton.Text = 'Start 3D';
            app.ResetButton = uibutton(app.HomeTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MitometerLabel = uilabel(app.HomeTab);
            app.MitometerLabel.Text = 'Mitometer';
            app.OpenFIJIButton = uibutton(app.HomeTab, 'push');
            app.OpenFIJIButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFIJIButtonPushed, true);
            app.OpenFIJIButton.Text = 'Open FIJI';
            app.IJButton = uibutton(app.HomeTab, 'push');
            app.IJButton.ButtonPushedFcn = createCallbackFcn(app, @IJButtonPushed, true);
            app.IJButton.Text = 'IJ';
            app.IJButton_2 = uibutton(app.HomeTab, 'push');
            app.IJButton_2.ButtonPushedFcn = createCallbackFcn(app, @IJButton_2Pushed, true);
            app.IJButton_2.Text = 'IJ';
            app.Label = uilabel(app.HomeTab);
            app.Label.Text = '';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.segFigOG = uiaxes(app.SegmentationTab);
            app.segFigOG.XTick = [];
            app.segFigOG.XTickLabel = '';
            app.segFigOG.YTick = [];
            app.segFigOG.YTickLabel = '';
            app.segFigMed = uiaxes(app.SegmentationTab);
            app.segFigMed.XTick = [];
            app.segFigMed.XTickLabel = '';
            app.segFigMed.YTick = [];
            app.segFigMed.YTickLabel = '';
            app.segFigObjects = uiaxes(app.SegmentationTab);
            app.segFigObjects.XTick = [];
            app.segFigObjects.XTickLabel = '';
            app.segFigObjects.YTick = [];
            app.segFigObjects.YTickLabel = '';
            app.segFigFinal = uiaxes(app.SegmentationTab);
            app.segFigFinal.XTick = [];
            app.segFigFinal.XTickLabel = '';
            app.segFigFinal.YTick = [];
            app.segFigFinal.YTickLabel = '';
            app.OriginalImageLabel = uilabel(app.SegmentationTab);
            app.OriginalImageLabel.Text = 'Original Image';
            app.DiffuseBackgroundRemovedLabel = uilabel(app.SegmentationTab);
            app.DiffuseBackgroundRemovedLabel.Text = 'Diffuse Background Removed';
            app.ObjectsRandomlyLabeled = uilabel(app.SegmentationTab);
            app.ObjectsRandomlyLabeled.Text = 'Objects Randomly Labeled';
            app.FinalImageLabel = uilabel(app.SegmentationTab);
            app.FinalImageLabel.Text = 'Final Image';
            app.SelectedImageButtonGroup = uibuttongroup(app.SegmentationTab);
            app.SelectedImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectedImageButtonGroupSelectionChanged, true);
            app.SelectedImageButtonGroup.Title = 'Selected Image';
            app.OriginalButton = uitogglebutton(app.SelectedImageButtonGroup);
            app.OriginalButton.Tag = '1';
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.DiffuseButton = uitogglebutton(app.SelectedImageButtonGroup);
            app.DiffuseButton.Tag = '2';
            app.DiffuseButton.Text = 'Diffuse';
            app.ObjectsButton = uitogglebutton(app.SelectedImageButtonGroup);
            app.ObjectsButton.Tag = '3';
            app.ObjectsButton.Text = 'Objects';
            app.FinalButton = uitogglebutton(app.SelectedImageButtonGroup);
            app.FinalButton.Tag = '4';
            app.FinalButton.Text = 'Final';
            app.FrameSliderLabel = uilabel(app.SegmentationTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.SegmentationTab);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.Spinner = uispinner(app.SegmentationTab);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.PlaySeg = uibutton(app.SegmentationTab, 'push');
            app.PlaySeg.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlaySeg.Text = 'Play';
            app.SavetifstackButton = uibutton(app.SegmentationTab, 'push');
            app.SavetifstackButton.ButtonPushedFcn = createCallbackFcn(app, @SavetifstackButtonPushed, true);
            app.SavetifstackButton.Text = 'Save .tif stack';
            app.Spinner_2 = uispinner(app.SegmentationTab);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Enable = 'off';
            app.Spinner_2.Value = 1;
            app.ZPlaneSliderLabel = uilabel(app.SegmentationTab);
            app.ZPlaneSliderLabel.Text = 'Z Plane';
            app.ZPlaneSlider = uislider(app.SegmentationTab);
            app.ZPlaneSlider.Limits = [1 100];
            app.ZPlaneSlider.ValueChangingFcn = createCallbackFcn(app, @ZPlaneSliderValueChanging, true);
            app.ZPlaneSlider.Enable = 'off';
            app.ZPlaneSlider.Value = 1;
            app.IJButton_3 = uibutton(app.SegmentationTab, 'push');
            app.IJButton_3.ButtonPushedFcn = createCallbackFcn(app, @IJButton_3Pushed, true);
            app.IJButton_3.Text = 'IJ';
            app.TracksTab = uitab(app.TabGroup);
            app.TracksTab.Title = 'Tracks';
            app.TrackFig = uiaxes(app.TracksTab);
            app.UIAxes_2 = uiaxes(app.TracksTab);
            ylabel(app.UIAxes_2, 'Frequency')
            app.TrackViewLabel = uilabel(app.TracksTab);
            app.TrackViewLabel.Text = 'Track View';
            app.DropDown = uidropdown(app.TracksTab);
            app.DropDown.Items = {'All tracks', 'Confident tracks', 'Single track', 'Perinuclear', 'Telenuclear'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'All tracks';
            app.TracklengththresholdLabel = uilabel(app.TracksTab);
            app.TracklengththresholdLabel.Text = 'Track length threshold';
            app.keptTracksThresh = uispinner(app.TracksTab);
            app.keptTracksThresh.Limits = [0 Inf];
            app.keptTracksThresh.ValueChangedFcn = createCallbackFcn(app, @keptTracksThreshValueChanged, true);
            app.keptTracksThresh.Enable = 'off';
            app.keptTracksThresh.Value = 1;
            app.TracktoviewLabel = uilabel(app.TracksTab);
            app.TracktoviewLabel.Text = 'Track to view';
            app.singleTrack = uispinner(app.TracksTab);
            app.singleTrack.Limits = [0 Inf];
            app.singleTrack.ValueChangedFcn = createCallbackFcn(app, @singleTrackValueChanged, true);
            app.singleTrack.Enable = 'off';
            app.singleTrack.Value = 1;
            app.ObjectCheckBox = uicheckbox(app.TracksTab);
            app.ObjectCheckBox.ValueChangedFcn = createCallbackFcn(app, @ObjectCheckBoxValueChanged, true);
            app.ObjectCheckBox.Text = 'Extrema';
            app.ZAspectRatioSpinnerLabel = uilabel(app.TracksTab);
            app.ZAspectRatioSpinnerLabel.Text = 'Z Aspect Ratio';
            app.ZAspectRatioSpinner = uispinner(app.TracksTab);
            app.ZAspectRatioSpinner.Limits = [1 Inf];
            app.ZAspectRatioSpinner.ValueChangedFcn = createCallbackFcn(app, @ZAspectRatioSpinnerValueChanged, true);
            app.ZAspectRatioSpinner.Enable = 'off';
            app.ZAspectRatioSpinner.Value = 1;
            app.SelectedButton = uibutton(app.TracksTab, 'push');
            app.SelectedButton.ButtonPushedFcn = createCallbackFcn(app, @SelectedButtonPushed, true);
            app.SelectedButton.Text = 'Selected';
            app.AllButton = uibutton(app.TracksTab, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.SavetracksLabel = uilabel(app.TracksTab);
            app.SavetracksLabel.Text = 'Save tracks';
            app.RemovetracksButton = uibutton(app.TracksTab, 'push');
            app.RemovetracksButton.ButtonPushedFcn = createCallbackFcn(app, @RemovetracksButtonPushed, true);
            app.RemovetracksButton.Text = 'Remove tracks';
            app.ConfirmButton = uibutton(app.TracksTab, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Enable = 'off';
            app.ConfirmButton.Text = 'Confirm';
            app.CancelButton = uibutton(app.TracksTab, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.ListBox_2 = uilistbox(app.TracksTab);
            app.ListBox_2.Items = {'Area (μm^2) / Volume (μm^3)', 'Major Axis Length (μm)', 'Minor Axis Length (μm)', 'Solidity', 'Perimeter (μm) / Surface Area (μm)', 'Mean Intensity', 'Fission', 'Fusion', 'Distance (μm)', 'Displacement (μm)', 'Speed (μm/s)', 'Velocity (μm/s)', 'Z Axis Length'};
            app.ListBox_2.ItemsData = {'Area', 'MajorAxisLength', 'MinorAxisLength', 'Solidity', 'Perimeter', 'MeanIntensity', 'fission', 'fusion', 'distance', 'displacement', 'speed', 'velocity', 'ZAxisLength'};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = {};
            app.SavetotxtButton_2 = uibutton(app.TracksTab, 'push');
            app.SavetotxtButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavetotxtButton_2Pushed, true);
            app.SavetotxtButton_2.Enable = 'off';
            app.SavetotxtButton_2.Text = 'Save to txt';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxes = uiaxes(app.AnalysisTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ListBox = uilistbox(app.AnalysisTab);
            app.ListBox.Items = {'Area (μm^2) / Volume (μm^3)', 'Major Axis Length (μm)', 'Minor Axis Length (μm)', 'Solidity', 'Perimeter (μm) / Surface Area (μm)', 'Mean Intensity', 'Fission', 'Fusion', 'Distance (μm)', 'Displacement (μm)', 'Speed (μm/s)', 'Velocity (μm/s)', 'Z Axis Length'};
            app.ListBox.ItemsData = {'Area', 'MajorAxisLength', 'MinorAxisLength', 'Solidity', 'Perimeter', 'MeanIntensity', 'fission', 'fusion', 'distance', 'displacement', 'speed', 'velocity', 'ZAxisLength'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.CreategroupButton = uibutton(app.AnalysisTab, 'push');
            app.CreategroupButton.ButtonPushedFcn = createCallbackFcn(app, @CreategroupButtonPushed, true);
            app.CreategroupButton.Text = 'Create group';
            app.GroupsDropDownLabel = uilabel(app.AnalysisTab);
            app.GroupsDropDownLabel.Text = 'Groups';
            app.GroupsDropDown = uidropdown(app.AnalysisTab);
            app.GroupsDropDown.Items = {};
            app.GroupsDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupsDropDownValueChanged, true);
            app.GroupsDropDown.Enable = 'off';
            app.GroupsDropDown.Value = {};
            app.AddtographButton = uibutton(app.AnalysisTab, 'push');
            app.AddtographButton.ButtonPushedFcn = createCallbackFcn(app, @AddtographButtonPushed, true);
            app.AddtographButton.Enable = 'off';
            app.AddtographButton.Text = 'Add to graph';
            app.SavetotxtButton = uibutton(app.AnalysisTab, 'push');
            app.SavetotxtButton.ButtonPushedFcn = createCallbackFcn(app, @SavetotxtButtonPushed, true);
            app.SavetotxtButton.Enable = 'off';
            app.SavetotxtButton.Text = 'Save to txt';
            app.LogscaleCheckBox = uicheckbox(app.AnalysisTab);
            app.LogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogscaleCheckBoxValueChanged, true);
            app.LogscaleCheckBox.Text = 'Log scale';
            app.ResetgraphButton = uibutton(app.AnalysisTab, 'push');
            app.ResetgraphButton.ButtonPushedFcn = createCallbackFcn(app, @ResetgraphButtonPushed, true);
            app.ResetgraphButton.Text = 'Reset graph';
            app.GraphtypeButtonGroup = uibuttongroup(app.AnalysisTab);
            app.GraphtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GraphtypeButtonGroupSelectionChanged, true);
            app.GraphtypeButtonGroup.Title = 'Graph type';
            app.HistogramButton = uiradiobutton(app.GraphtypeButtonGroup);
            app.HistogramButton.Text = 'Histogram';
            app.HistogramButton.Value = true;
            app.MedianBarButton = uiradiobutton(app.GraphtypeButtonGroup);
            app.MedianBarButton.Text = 'Median Bar';
            app.pvalueEditFieldLabel = uilabel(app.AnalysisTab);
            app.pvalueEditFieldLabel.Text = 'p-value';
            app.pvalueEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.pvalueEditField.Editable = 'off';
            app.pvalueEditField.Value = 1;
            app.OnewayANOVALabel = uilabel(app.AnalysisTab);
            app.OnewayANOVALabel.Text = 'One-way ANOVA';
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.SegmentationPanel = uipanel(app.AdvancedSettingsTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.MinimummitochondrialareaumEditFieldLabel = uilabel(app.SegmentationPanel);
            app.MinimummitochondrialareaumEditFieldLabel.Text = 'Minimum mitochondrial area (um)';
            app.customMinArea = uieditfield(app.SegmentationPanel, 'numeric');
            app.customMinArea.Limits = [0 Inf];
            app.customMinArea.ValueChangedFcn = createCallbackFcn(app, @customMinAreaValueChanged, true);
            app.customMinArea.Value = 0.3;
            app.MaximummitochondrialareaumEditFieldLabel = uilabel(app.SegmentationPanel);
            app.MaximummitochondrialareaumEditFieldLabel.Text = 'Maximum mitochondrial area (um)';
            app.customMaxArea = uieditfield(app.SegmentationPanel, 'numeric');
            app.customMaxArea.Limits = [0 Inf];
            app.customMaxArea.ValueChangedFcn = createCallbackFcn(app, @customMaxAreaValueChanged, true);
            app.customMaxArea.Value = 200;
            app.CustomgaussfiltsigmaEditFieldLabel = uilabel(app.SegmentationPanel);
            app.CustomgaussfiltsigmaEditFieldLabel.Text = 'Custom gauss filt sigma';
            app.customSigVal = uieditfield(app.SegmentationPanel, 'numeric');
            app.customSigVal.Limits = [0 Inf];
            app.customSigVal.ValueChangedFcn = createCallbackFcn(app, @customSigValValueChanged, true);
            app.customSigVal.Enable = 'off';
            app.gaussCheckbox = uicheckbox(app.SegmentationPanel);
            app.gaussCheckbox.ValueChangedFcn = createCallbackFcn(app, @gaussCheckboxValueChanged, true);
            app.gaussCheckbox.Text = '';
            app.CustomthresholdEditFieldLabel = uilabel(app.SegmentationPanel);
            app.CustomthresholdEditFieldLabel.Text = 'Custom threshold';
            app.customThrVal = uieditfield(app.SegmentationPanel, 'numeric');
            app.customThrVal.Limits = [0 Inf];
            app.customThrVal.ValueChangedFcn = createCallbackFcn(app, @customThrValValueChanged, true);
            app.customThrVal.Enable = 'off';
            app.threshCheckbox = uicheckbox(app.SegmentationPanel);
            app.threshCheckbox.ValueChangedFcn = createCallbackFcn(app, @threshCheckboxValueChanged, true);
            app.threshCheckbox.Text = '';
            app.TrackingPanel = uipanel(app.AdvancedSettingsTab);
            app.TrackingPanel.Title = 'Tracking';
            app.MaxvelocitythresholdmsEditFieldLabel = uilabel(app.TrackingPanel);
            app.MaxvelocitythresholdmsEditFieldLabel.Text = 'Max velocity threshold (μm/s)';
            app.customDistThresh = uieditfield(app.TrackingPanel, 'numeric');
            app.customDistThresh.Limits = [0 Inf];
            app.customDistThresh.ValueChangedFcn = createCallbackFcn(app, @customDistThreshValueChanged, true);
            app.customDistThresh.Value = 1;
            app.MaxwaittimethresholdsEditFieldLabel = uilabel(app.TrackingPanel);
            app.MaxwaittimethresholdsEditFieldLabel.Text = 'Max wait time threshold (s)';
            app.customFrameThresh = uieditfield(app.TrackingPanel, 'numeric');
            app.customFrameThresh.Limits = [0 Inf];
            app.customFrameThresh.ValueChangedFcn = createCallbackFcn(app, @customFrameThreshValueChanged, true);
            app.customFrameThresh.Value = Inf;
            app.fisFusCheckbox = uicheckbox(app.TrackingPanel);
            app.fisFusCheckbox.ValueChangedFcn = createCallbackFcn(app, @fisFusCheckboxValueChanged, true);
            app.fisFusCheckbox.Text = 'Skip fission and fusion';
            app.CustomweightsLabel = uilabel(app.TrackingPanel);
            app.CustomweightsLabel.Text = 'Custom weights';
            app.DistanceEditFieldLabel = uilabel(app.TrackingPanel);
            app.DistanceEditFieldLabel.Visible = 'off';
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.customDistWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customDistWeight.Limits = [0 Inf];
            app.customDistWeight.ValueChangedFcn = createCallbackFcn(app, @customDistWeightValueChanged, true);
            app.customDistWeight.Enable = 'off';
            app.customDistWeight.Visible = 'off';
            app.AreaVolumeEditFieldLabel = uilabel(app.TrackingPanel);
            app.AreaVolumeEditFieldLabel.Text = 'Area/Volume';
            app.customAreaWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customAreaWeight.Limits = [0 Inf];
            app.customAreaWeight.ValueChangedFcn = createCallbackFcn(app, @customAreaWeightValueChanged, true);
            app.customAreaWeight.Enable = 'off';
            app.customAreaWeight.Value = 1;
            app.MajorAxisEditFieldLabel = uilabel(app.TrackingPanel);
            app.MajorAxisEditFieldLabel.Text = 'Major Axis';
            app.customMajAxWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customMajAxWeight.Limits = [0 Inf];
            app.customMajAxWeight.ValueChangedFcn = createCallbackFcn(app, @customMajAxWeightValueChanged, true);
            app.customMajAxWeight.Enable = 'off';
            app.customMajAxWeight.Value = 1;
            app.MinorAxisEditFieldLabel = uilabel(app.TrackingPanel);
            app.MinorAxisEditFieldLabel.Text = 'Minor Axis';
            app.customMinAxWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customMinAxWeight.Limits = [0 Inf];
            app.customMinAxWeight.ValueChangedFcn = createCallbackFcn(app, @customMinAxWeightValueChanged, true);
            app.customMinAxWeight.Enable = 'off';
            app.customMinAxWeight.Value = 1;
            app.SolidityEditFieldLabel = uilabel(app.TrackingPanel);
            app.SolidityEditFieldLabel.Text = 'Solidity';
            app.customSolWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customSolWeight.Limits = [0 Inf];
            app.customSolWeight.ValueChangedFcn = createCallbackFcn(app, @customSolWeightValueChanged, true);
            app.customSolWeight.Enable = 'off';
            app.customSolWeight.Value = 1;
            app.PerimeterSurfaceAreaEditFieldLabel = uilabel(app.TrackingPanel);
            app.PerimeterSurfaceAreaEditFieldLabel.Text = 'Perimeter/Surface Area';
            app.customPeriWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customPeriWeight.Limits = [0 Inf];
            app.customPeriWeight.ValueChangedFcn = createCallbackFcn(app, @customPeriWeightValueChanged, true);
            app.customPeriWeight.Enable = 'off';
            app.customPeriWeight.Value = 1;
            app.IntensityEditFieldLabel = uilabel(app.TrackingPanel);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.customIntWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customIntWeight.Limits = [0 Inf];
            app.customIntWeight.ValueChangedFcn = createCallbackFcn(app, @customIntWeightValueChanged, true);
            app.customIntWeight.Enable = 'off';
            app.customIntWeight.Value = 1;
            app.ZAxis3DonlyEditFieldLabel = uilabel(app.TrackingPanel);
            app.ZAxis3DonlyEditFieldLabel.Text = 'Z Axis (3D only)';
            app.customZAxWeight = uieditfield(app.TrackingPanel, 'numeric');
            app.customZAxWeight.Limits = [0 Inf];
            app.customZAxWeight.ValueChangedFcn = createCallbackFcn(app, @customZAxWeightValueChanged, true);
            app.customZAxWeight.Enable = 'off';
            app.customZAxWeight.Value = 1;
            app.customWeightCheckbox = uicheckbox(app.TrackingPanel);
            app.customWeightCheckbox.ValueChangedFcn = createCallbackFcn(app, @customWeightCheckboxValueChanged, true);
            app.customWeightCheckbox.Text = '';
            app.TroubleshootTab = uitab(app.TabGroup);
            app.TroubleshootTab.Title = 'Troubleshoot';
            app.SegmentationPanel_2 = uipanel(app.TroubleshootTab);
            app.SegmentationPanel_2.Title = 'Segmentation';
            app.segFigFinal_2 = uiaxes(app.SegmentationPanel_2);
            app.segFigFinal_2.XTick = [];
            app.segFigFinal_2.XTickLabel = '';
            app.segFigFinal_2.YTick = [];
            app.segFigFinal_2.YTickLabel = '';
            app.MinimummitochondrialareaumEditFieldLabel_3 = uilabel(app.SegmentationPanel_2);
            app.MinimummitochondrialareaumEditFieldLabel_3.Text = 'Minimum mitochondrial area (um)';
            app.customMinArea_3 = uieditfield(app.SegmentationPanel_2, 'numeric');
            app.customMinArea_3.Limits = [0 Inf];
            app.customMinArea_3.ValueChangedFcn = createCallbackFcn(app, @customMinArea_3ValueChanged, true);
            app.customMinArea_3.Value = 0.3;
            app.MaximummitochondrialareaumEditFieldLabel_3 = uilabel(app.SegmentationPanel_2);
            app.MaximummitochondrialareaumEditFieldLabel_3.Text = 'Maximum mitochondrial area (um)';
            app.customMaxArea_3 = uieditfield(app.SegmentationPanel_2, 'numeric');
            app.customMaxArea_3.Limits = [0 Inf];
            app.customMaxArea_3.ValueChangedFcn = createCallbackFcn(app, @customMaxArea_3ValueChanged, true);
            app.customMaxArea_3.Value = 200;
            app.CustomgaussfiltsigmaEditFieldLabel_3 = uilabel(app.SegmentationPanel_2);
            app.CustomgaussfiltsigmaEditFieldLabel_3.Text = 'Custom gauss filt sigma';
            app.customSigVal_3 = uieditfield(app.SegmentationPanel_2, 'numeric');
            app.customSigVal_3.Limits = [0 Inf];
            app.customSigVal_3.ValueChangedFcn = createCallbackFcn(app, @customSigVal_3ValueChanged, true);
            app.customSigVal_3.Enable = 'off';
            app.gaussCheckbox_3 = uicheckbox(app.SegmentationPanel_2);
            app.gaussCheckbox_3.ValueChangedFcn = createCallbackFcn(app, @gaussCheckbox_3ValueChanged, true);
            app.gaussCheckbox_3.Text = '';
            app.CustomthresholdEditFieldLabel_3 = uilabel(app.SegmentationPanel_2);
            app.CustomthresholdEditFieldLabel_3.Text = 'Custom threshold';
            app.customThrVal_3 = uieditfield(app.SegmentationPanel_2, 'numeric');
            app.customThrVal_3.Limits = [0 Inf];
            app.customThrVal_3.ValueChangedFcn = createCallbackFcn(app, @customThrVal_3ValueChanged, true);
            app.customThrVal_3.Enable = 'off';
            app.threshCheckbox_3 = uicheckbox(app.SegmentationPanel_2);
            app.threshCheckbox_3.ValueChangedFcn = createCallbackFcn(app, @threshCheckbox_3ValueChanged, true);
            app.threshCheckbox_3.Text = '';
            app.SetButton = uibutton(app.SegmentationPanel_2, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Enable = 'off';
            app.SetButton.Text = 'Set';
            app.SetButton_2 = uibutton(app.SegmentationPanel_2, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Enable = 'off';
            app.SetButton_2.Text = 'Set';
            app.SetButton_3 = uibutton(app.SegmentationPanel_2, 'push');
            app.SetButton_3.ButtonPushedFcn = createCallbackFcn(app, @SetButton_3Pushed, true);
            app.SetButton_3.Enable = 'off';
            app.SetButton_3.Text = 'Set';
            app.SetButton_4 = uibutton(app.SegmentationPanel_2, 'push');
            app.SetButton_4.ButtonPushedFcn = createCallbackFcn(app, @SetButton_4Pushed, true);
            app.SetButton_4.Enable = 'off';
            app.SetButton_4.Text = 'Set';
            app.GoButton = uibutton(app.SegmentationPanel_2, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go';
            app.Spinner_3 = uispinner(app.SegmentationPanel_2);
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.PlaySeg_2 = uibutton(app.SegmentationPanel_2, 'push');
            app.PlaySeg_2.ButtonPushedFcn = createCallbackFcn(app, @PlaySeg_2ButtonPushed, true);
            app.PlaySeg_2.Text = 'Play';
            app.Spinner_4 = uispinner(app.SegmentationPanel_2);
            app.Spinner_4.ValueChangedFcn = createCallbackFcn(app, @Spinner_4ValueChanged, true);
            app.Spinner_4.Enable = 'off';
            app.Spinner_4.Value = 1;
            app.FrameSlider_2Label = uilabel(app.SegmentationPanel_2);
            app.FrameSlider_2Label.Text = 'Frame';
            app.FrameSlider_2 = uislider(app.SegmentationPanel_2);
            app.FrameSlider_2.ValueChangingFcn = createCallbackFcn(app, @FrameSlider_2ValueChanging, true);
            app.ZPlaneSlider_2Label = uilabel(app.SegmentationPanel_2);
            app.ZPlaneSlider_2Label.Text = 'Z Plane';
            app.ZPlaneSlider_2 = uislider(app.SegmentationPanel_2);
            app.ZPlaneSlider_2.Limits = [1 100];
            app.ZPlaneSlider_2.ValueChangingFcn = createCallbackFcn(app, @ZPlaneSlider_2ValueChanging, true);
            app.ZPlaneSlider_2.Enable = 'off';
            app.ZPlaneSlider_2.Value = 1;
            app.IJButton_5 = uibutton(app.SegmentationPanel_2, 'push');
            app.IJButton_5.ButtonPushedFcn = createCallbackFcn(app, @IJButton_5Pushed, true);
            app.IJButton_5.Text = 'IJ';
            app.TrackingPanel_2 = uipanel(app.TroubleshootTab);
            app.TrackingPanel_2.Title = 'Tracking';
            app.TrackFig_2 = uiaxes(app.TrackingPanel_2);
            app.MaxvelocitythresholdmsEditFieldLabel_2 = uilabel(app.TrackingPanel_2);
            app.MaxvelocitythresholdmsEditFieldLabel_2.Text = 'Max velocity threshold (μm/s)';
            app.customDistThresh_2 = uieditfield(app.TrackingPanel_2, 'numeric');
            app.customDistThresh_2.Limits = [0 Inf];
            app.customDistThresh_2.ValueChangedFcn = createCallbackFcn(app, @customDistThresh_2ValueChanged, true);
            app.customDistThresh_2.Value = 1;
            app.MaxwaittimethresholdsEditFieldLabel_2 = uilabel(app.TrackingPanel_2);
            app.MaxwaittimethresholdsEditFieldLabel_2.Text = 'Max wait time threshold (s)';
            app.customFrameThresh_2 = uieditfield(app.TrackingPanel_2, 'numeric');
            app.customFrameThresh_2.Limits = [0 Inf];
            app.customFrameThresh_2.ValueChangedFcn = createCallbackFcn(app, @customFrameThresh_2ValueChanged, true);
            app.customFrameThresh_2.Value = Inf;
            app.fisFusCheckbox_2 = uicheckbox(app.TrackingPanel_2);
            app.fisFusCheckbox_2.Text = 'Skip fission and fusion';
            app.CustomweightsLabel_2 = uilabel(app.TrackingPanel_2);
            app.CustomweightsLabel_2.Text = 'Custom weights';
            app.AreaVolumeEditFieldLabel_2 = uilabel(app.TrackingPanel_2);
            app.AreaVolumeEditFieldLabel_2.Text = 'Area/Volume';
            app.customAreaWeight_2 = uieditfield(app.TrackingPanel_2, 'numeric');
            app.customAreaWeight_2.Limits = [0 Inf];
            app.customAreaWeight_2.ValueChangedFcn = createCallbackFcn(app, @customAreaWeight_2ValueChanged, true);
            app.customAreaWeight_2.Enable = 'off';
            app.customAreaWeight_2.Value = 1;
            app.MajorAxisEditFieldLabel_2 = uilabel(app.TrackingPanel_2);
            app.MajorAxisEditFieldLabel_2.Text = 'Major Axis';
            app.customMajAxWeight_2 = uieditfield(app.TrackingPanel_2, 'numeric');
            app.customMajAxWeight_2.Limits = [0 Inf];
            app.customMajAxWeight_2.ValueChangedFcn = createCallbackFcn(app, @customMajAxWeight_2ValueChanged, true);
            app.customMajAxWeight_2.Enable = 'off';
            app.customMajAxWeight_2.Value = 1;
            app.MinorAxisEditFieldLabel_2 = uilabel(app.TrackingPanel_2);
            app.MinorAxisEditFieldLabel_2.Text = 'Minor Axis';
            app.customMinAxWeight_2 = uieditfield(app.TrackingPanel_2, 'numeric');
            app.customMinAxWeight_2.Limits = [0 Inf];
            app.customMinAxWeight_2.ValueChangedFcn = createCallbackFcn(app, @customMinAxWeight_2ValueChanged, true);
            app.customMinAxWeight_2.Enable = 'off';
            app.customMinAxWeight_2.Value = 1;
            app.SolidityEditFieldLabel_2 = uilabel(app.TrackingPanel_2);
            app.SolidityEditFieldLabel_2.Text = 'Solidity';
            app.customSolWeight_2 = uieditfield(app.TrackingPanel_2, 'numeric');
            app.customSolWeight_2.Limits = [0 Inf];
            app.customSolWeight_2.ValueChangedFcn = createCallbackFcn(app, @customSolWeight_2ValueChanged, true);
            app.customSolWeight_2.Enable = 'off';
            app.customSolWeight_2.Value = 1;
            app.PerimeterSurfaceAreaEditFieldLabel_2 = uilabel(app.TrackingPanel_2);
            app.PerimeterSurfaceAreaEditFieldLabel_2.Text = 'Perimeter/Surface Area';
            app.customPeriWeight_2 = uieditfield(app.TrackingPanel_2, 'numeric');
            app.customPeriWeight_2.Limits = [0 Inf];
            app.customPeriWeight_2.ValueChangedFcn = createCallbackFcn(app, @customPeriWeight_2ValueChanged, true);
            app.customPeriWeight_2.Enable = 'off';
            app.customPeriWeight_2.Value = 1;
            app.IntensityEditFieldLabel_2 = uilabel(app.TrackingPanel_2);
            app.IntensityEditFieldLabel_2.Text = 'Intensity';
            app.customIntWeight_2 = uieditfield(app.TrackingPanel_2, 'numeric');
            app.customIntWeight_2.Limits = [0 Inf];
            app.customIntWeight_2.ValueChangedFcn = createCallbackFcn(app, @customIntWeight_2ValueChanged, true);
            app.customIntWeight_2.Enable = 'off';
            app.customIntWeight_2.Value = 1;
            app.ZAxis3DonlyEditFieldLabel_2 = uilabel(app.TrackingPanel_2);
            app.ZAxis3DonlyEditFieldLabel_2.Text = 'Z Axis (3D only)';
            app.customZAxWeight_2 = uieditfield(app.TrackingPanel_2, 'numeric');
            app.customZAxWeight_2.Limits = [0 Inf];
            app.customZAxWeight_2.ValueChangedFcn = createCallbackFcn(app, @customZAxWeight_2ValueChanged, true);
            app.customZAxWeight_2.Enable = 'off';
            app.customZAxWeight_2.Value = 1;
            app.customWeightCheckbox_2 = uicheckbox(app.TrackingPanel_2);
            app.customWeightCheckbox_2.ValueChangedFcn = createCallbackFcn(app, @customWeightCheckbox_2ValueChanged, true);
            app.customWeightCheckbox_2.Text = '';
            app.SetButton_5 = uibutton(app.TrackingPanel_2, 'push');
            app.SetButton_5.ButtonPushedFcn = createCallbackFcn(app, @SetButton_5Pushed, true);
            app.SetButton_5.Enable = 'off';
            app.SetButton_5.Text = 'Set';
            app.SetButton_6 = uibutton(app.TrackingPanel_2, 'push');
            app.SetButton_6.ButtonPushedFcn = createCallbackFcn(app, @SetButton_6Pushed, true);
            app.SetButton_6.Enable = 'off';
            app.SetButton_6.Text = 'Set';
            app.SetButton_7 = uibutton(app.TrackingPanel_2, 'push');
            app.SetButton_7.ButtonPushedFcn = createCallbackFcn(app, @SetButton_7Pushed, true);
            app.SetButton_7.Enable = 'off';
            app.SetButton_7.Text = 'Set';
            app.GoButton_2 = uibutton(app.TrackingPanel_2, 'push');
            app.GoButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoButton_2Pushed, true);
            app.GoButton_2.Enable = 'off';
            app.GoButton_2.Text = 'Go';
            app.Upload2DButton = uibutton(app.TroubleshootTab, 'push');
            app.Upload2DButton.ButtonPushedFcn = createCallbackFcn(app, @Upload2DButtonPushed, true);
            app.Upload2DButton.Text = 'Upload 2D';
            app.Upload3DButton = uibutton(app.TroubleshootTab, 'push');
            app.Upload3DButton.ButtonPushedFcn = createCallbackFcn(app, @Upload3DButtonPushed, true);
            app.Upload3DButton.Text = 'Upload 3D';
            app.LoadingLabel = uilabel(app.MitometerUIFigure);
            app.LoadingLabel.Visible = 'off';
            app.LoadingLabel.Text = 'Loading...';
            app.ImageList = uilistbox(app.MitometerUIFigure);
            app.ImageList.Items = {};
            app.ImageList.Multiselect = 'on';
            app.ImageList.ValueChangedFcn = createCallbackFcn(app, @ImageListValueChanged, true);
            app.ImageList.Value = {};
            app.SelectfilesLabel = uilabel(app.MitometerUIFigure);
            app.SelectfilesLabel.Text = 'Select file(s)';
            app.SelecthighlightedButton = uibutton(app.MitometerUIFigure, 'push');
            app.SelecthighlightedButton.ButtonPushedFcn = createCallbackFcn(app, @SelecthighlightedButtonPushed, true);
            app.SelecthighlightedButton.Text = 'Select highlighted';
            % Show the figure after all components are created
            app.MitometerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MitometerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MitometerUIFigure)
        end
    end
end